classdef ESCWaveformGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ESCWaveGen                      matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SignalSources                   matlab.ui.container.Tab
        SelectDataFolderRadar           matlab.ui.control.Button
        RadarFilesLabel                 matlab.ui.control.Label
        RadarFiles                      matlab.ui.control.ListBox
        SelectDataFolderLTE             matlab.ui.control.Button
        LTEDirLbl                       matlab.ui.control.Label
        RadarDirLbl                     matlab.ui.control.Label
        RadarMetaFileLbl                matlab.ui.control.Label
        SelectRadarMetaDataFile         matlab.ui.control.Button
        SetRadarReadScaleManually       matlab.ui.control.CheckBox
        RadarReadScaleEdt               matlab.ui.control.NumericEditField
        LTEFilesLabel                   matlab.ui.control.Label
        LTEFiles                        matlab.ui.control.ListBox
        SelectedLTEFilesLabel           matlab.ui.control.Label
        SelectedLTEFiles                matlab.ui.control.ListBox
        SelectLTE                       matlab.ui.control.Button
        SelectRadar                     matlab.ui.control.Button
        SelectedRadarFilesLabel         matlab.ui.control.Label
        SelectedRadarFiles              matlab.ui.control.ListBox
        SelectABI                       matlab.ui.control.Button
        SelectedABIFilesLabel           matlab.ui.control.Label
        SelectedABIFiles                matlab.ui.control.ListBox
        LTEReadScaleEditFieldLabel      matlab.ui.control.Label
        LTEReadScaleEdt                 matlab.ui.control.NumericEditField
        WaveformPreview                 matlab.ui.container.Tab
        SignalTiming                    matlab.ui.container.Panel
        TotalpreviewLabel               matlab.ui.control.Label
        TotalPreviewTime                matlab.ui.control.NumericEditField
        SegmentPreviewTimeLabel         matlab.ui.control.Label
        SegmentPreviewTime              matlab.ui.control.NumericEditField
        PanelLTE2                       matlab.ui.container.Panel
        LTE2LED                         matlab.ui.control.Lamp
        SliderFreqOffsetLTE2            matlab.ui.control.Slider
        Label13                         matlab.ui.control.Label
        Label15                         matlab.ui.control.Label
        SwitchLTE2                      matlab.ui.control.ToggleSwitch
        LTE2FreqNum                     matlab.ui.control.NumericEditField
        Label14                         matlab.ui.control.Label
        KnobGainLTE2                    matlab.ui.control.Knob
        GainLTE2Num                     matlab.ui.control.NumericEditField
        ChannelTypeLTE1_2Label          matlab.ui.control.Label
        ChannelTypeLTE2                 matlab.ui.control.DropDown
        PanelLTE1                       matlab.ui.container.Panel
        LTE1LED                         matlab.ui.control.Lamp
        SliderFreqOffsetLTE1            matlab.ui.control.Slider
        Label17                         matlab.ui.control.Label
        Label18                         matlab.ui.control.Label
        KnobGainLTE1                    matlab.ui.control.Knob
        Label19                         matlab.ui.control.Label
        SwitchLTE1                      matlab.ui.control.ToggleSwitch
        LTE1FreqNum                     matlab.ui.control.NumericEditField
        GainLTE1Num                     matlab.ui.control.NumericEditField
        ChannelTypeDropDownLabel        matlab.ui.control.Label
        ChannelTypeLTE1                 matlab.ui.control.DropDown
        PanelRadar1                     matlab.ui.container.Panel
        Radar1LED                       matlab.ui.control.Lamp
        SliderFreqOffsetRadar1          matlab.ui.control.Slider
        Label21                         matlab.ui.control.Label
        Label22                         matlab.ui.control.Label
        KnobGainRadar1                  matlab.ui.control.Knob
        SwitchRadar1                    matlab.ui.control.ToggleSwitch
        Radar1FreqNum                   matlab.ui.control.NumericEditField
        GainRadar1Num                   matlab.ui.control.NumericEditField
        Label23                         matlab.ui.control.Label
        Waveforms                       matlab.ui.container.Panel
        Stop                            matlab.ui.control.Button
        Preview                         matlab.ui.control.Button
        DisplayPreview                  matlab.ui.container.ButtonGroup
        Frequency                       matlab.ui.control.RadioButton
        Time                            matlab.ui.control.RadioButton
        Spectrogram                     matlab.ui.control.RadioButton
        ReadyStateLbl                   matlab.ui.control.Label
        SIRLbl                          matlab.ui.control.Label
        ShowSIR                         matlab.ui.control.CheckBox
        LTEChannelState                 matlab.ui.control.CheckBox
        LoadDefaults                    matlab.ui.control.Button
        PanelRadar2                     matlab.ui.container.Panel
        Radar2LED                       matlab.ui.control.Lamp
        SliderFreqOffsetRadar2          matlab.ui.control.Slider
        Label21_2                       matlab.ui.control.Label
        Label22_2                       matlab.ui.control.Label
        KnobGainRadar2                  matlab.ui.control.Knob
        SwitchRadar2                    matlab.ui.control.ToggleSwitch
        Label23_2                       matlab.ui.control.Label
        Radar2FreqNum                   matlab.ui.control.NumericEditField
        GainRadar2Num                   matlab.ui.control.NumericEditField
        PanelABI                        matlab.ui.container.Panel
        ABILED                          matlab.ui.control.Lamp
        SliderFreqOffsetABI             matlab.ui.control.Slider
        Label21_3                       matlab.ui.control.Label
        Label22_3                       matlab.ui.control.Label
        KnobGainABI                     matlab.ui.control.Knob
        SwitchABI                       matlab.ui.control.ToggleSwitch
        Label23_3                       matlab.ui.control.Label
        ABIFreqNum                      matlab.ui.control.NumericEditField
        GainABINum                      matlab.ui.control.NumericEditField
        AWGNPanel                       matlab.ui.container.Panel
        AWGN                            matlab.ui.control.CheckBox
        AWGNVar                         matlab.ui.control.Slider
        AWGNVarNum                      matlab.ui.control.NumericEditField
        TargetSIRPanel                  matlab.ui.container.Panel
        TargetSIRdBEditFieldLabel       matlab.ui.control.Label
        TargetSIRdBEdt                  matlab.ui.control.NumericEditField
        ForwardPreviewSignalssecPanel   matlab.ui.container.Panel
        Radar1Label                     matlab.ui.control.Label
        ForwardRadar1                   matlab.ui.control.NumericEditField
        Radar2Label                     matlab.ui.control.Label
        ForwardRadar2                   matlab.ui.control.NumericEditField
        ABILabel                        matlab.ui.control.Label
        ForwardABI                      matlab.ui.control.NumericEditField
        LTE1Label                       matlab.ui.control.Label
        ForwardLTE1                     matlab.ui.control.NumericEditField
        LTE1Label_2                     matlab.ui.control.Label
        ForwardLTE2                     matlab.ui.control.NumericEditField
        ShowPeaks                       matlab.ui.control.Button
        SignalPowerLevels               matlab.ui.container.Panel
        RadarPeakPowerEditFieldLabel    matlab.ui.control.Label
        RadarPeakPowerEdt               matlab.ui.control.NumericEditField
        LTEPowerEditFieldLabel          matlab.ui.control.Label
        LTEPowerEdt                     matlab.ui.control.NumericEditField
        ABIPowerEditFieldLabel          matlab.ui.control.Label
        ABIPowerEdt                     matlab.ui.control.NumericEditField
        AWGNPSDEditFieldLabel           matlab.ui.control.Label
        AWGNPSDEdt                      matlab.ui.control.NumericEditField
        GainMethodDropDownLabel         matlab.ui.control.Label
        GainMethod                      matlab.ui.control.DropDown
        EstimateGainParametersButton    matlab.ui.control.Button
        WaveformGenerationTab           matlab.ui.container.Tab
        SingleFileGenerationPanel       matlab.ui.container.Panel
        SaveWaveformAs                  matlab.ui.control.Button
        SingleFileGenIndlbl             matlab.ui.control.Label
        SaveWavefomAsedt                matlab.ui.control.EditField
        GenerateThisWaveform            matlab.ui.control.Button
        MultipleFileGenerationPanel     matlab.ui.container.Panel
        SaveWaveformDir                 matlab.ui.control.Button
        GenerateWaveforms               matlab.ui.control.Button
        SaveWavefomsDiredt              matlab.ui.control.EditField
        ParallelProcess                 matlab.ui.control.CheckBox
        SetNumberofWorkersLabel         matlab.ui.control.Label
        SetNumberofWorkers              matlab.ui.control.Spinner
        AvailableNumberofWorkers        matlab.ui.control.Label
        MultiFileGenIndlbl              matlab.ui.control.Label
        WaveformFilePrefixLabel         matlab.ui.control.Label
        WaveformFilePrefixedt           matlab.ui.control.EditField
        NumberofFilesSpinnerLabel       matlab.ui.control.Label
        NumberOfFiles                   matlab.ui.control.Spinner
        MultiFileMethod                 matlab.ui.control.Table
        MultiFileStep                   matlab.ui.control.Table
        MultiFileBounds                 matlab.ui.control.Table
        FileSources                     matlab.ui.container.Panel
        RadarSignalsDirGen              matlab.ui.control.Label
        RadarMetaFileGen                matlab.ui.control.Label
        LTESignalsDirGen                matlab.ui.control.Label
        FixedParametersTimesecFrequencyPanel  matlab.ui.container.Panel
        SingleFileFixedPar1             matlab.ui.control.Table
        SingleFileFixedPar2             matlab.ui.control.Table
        SingleFileFixedParLTECh         matlab.ui.control.Table
        PowerLevels                     matlab.ui.control.Table
        GainMethodGen                   matlab.ui.container.ButtonGroup
        TargetSIRMethod                 matlab.ui.control.RadioButton
        PowerLevelsMethod               matlab.ui.control.RadioButton
        WaveformPlayerTab               matlab.ui.container.Tab
        DisplayPlayer                   matlab.ui.container.ButtonGroup
        FrequencyPlayer                 matlab.ui.control.RadioButton
        TimePlayer                      matlab.ui.control.RadioButton
        SpectrogramButton               matlab.ui.control.RadioButton
        StopPlayer                      matlab.ui.control.Button
        PlayPlayer                      matlab.ui.control.Button
        SelectWaveformFolderPlayer      matlab.ui.control.Button
        SelectRadarFile_2Label          matlab.ui.control.Label
        SelectWaveformFilePlayer        matlab.ui.control.ListBox
        WaveformInfoLabel               matlab.ui.control.Label
        WaveformInfo                    matlab.ui.control.TextArea
        SetdataScaleGainEditFieldLabel  matlab.ui.control.Label
        SetdataScaleGainEdt             matlab.ui.control.NumericEditField
        LoadPreviewParameters           matlab.ui.control.Button
        ResetSpectrumAnalyzerandTimeSope  matlab.ui.control.Button
        DebugLbl                        matlab.ui.control.Label
        LogoImage                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %currDir; % Description
        Fs;
        hSpecAn;
        hTScope; 
        hSpectr;
        peaksFigure
        peaksAxes
        StopSimState=false;
        DisplayPlayState=1; % Frequency=1;Time=2; Spectrogram 3
        DisplayPreviewState=1; % Frequency=1;Time=2; Spectrogram 3
        StopPlayState=false;
        %WaveformSaveflag=0;
        %SaveWaveformFilePath;

        %% Defaults
        Parameters;
        %radarSignalsPaths
        folderBrowser 
        waveformPlayer
        waveformPervGen  % waveform preview and single generator
        %waveformMultiGen
        %waveformPervGenReady=true
        numRadarSignals=2
        numLTESignals=2
        numABISignals=1
        appDir
        configDir
        resDir
        dspDir
        utilDir
        playerSamplesPerSegment=1024*32;
        ThreeGPPChTypes
        %parState
    end
    
    
    methods (Access = private)


        %function cdIfNotDepComp(app,cdDir)
        %   if ~(ismcc || isdeployed)
        %        cd(cdDir);
        %    end
        %    
        %end
        
        function loadDefaults(app)
            %ConfigFileName='ESCWGConfigurationEmptyDir.json';
            ConfigFileName='ESCWGConfiguration.json';
            ConfigFile=fullfile(app.configDir,ConfigFileName);
            try
                ConfigText =fileread(ConfigFile);
                ParStruct=jsondecode(ConfigText);
            catch msg
                %assignin('base','msg',msg);
                uialert(app.ESCWaveGen,msg.message,'Error: Please close program and address the issue');
            end
            app.Parameters=ParStruct.Parameters;
            assignin('base','Parameters',  app.Parameters);
            app.Fs=app.Parameters.Signal.Fs;
            createWaveform(app);
            %assignin('base','waveformPervGen1',app.waveformPervGen)
            %check if radarMetaFile exists
            if (exist(app.Parameters.Signal.Source.Path.radarMetaFile, 'file') == 2) 
                app.RadarMetaFileLbl.Text=app.Parameters.Signal.Source.Path.radarMetaFile;
                app.SelectRadarMetaDataFile.Enable= 'off';
            end
            
            %app.generateWaveform=setUpLTECh(app.generateWaveform,struct2cell(app.Parameters.Signal.LTEChannelType)',app.LTEChNum);
            %app.ThreeGPPChTypes=get3GPPInfo(app.generateWaveform);
            app.ThreeGPPChTypes=get3GPPTypes(threeGPPChannel);
            app.ChannelTypeLTE1.Items=app.ThreeGPPChTypes;
            app.ChannelTypeLTE2.Items=app.ThreeGPPChTypes;
            %to do need to add selected from default
            app.ChannelTypeLTE1.Value=app.Parameters.Signal.LTEChannelType.LTE1;
            app.ChannelTypeLTE2.Value=app.Parameters.Signal.LTEChannelType.LTE2;
            
            app.RadarReadScaleEdt.Enable='off';
            app.RadarReadScaleEdt.Value=app.Parameters.Signal.RadarReadScale;
            app.SetRadarReadScaleManually.Value=app.Parameters.Signal.ManualRadarReadScale;
            app.LTEReadScaleEdt.Value=app.Parameters.Signal.LTEReadScaleFactor;
            %% Initialize Freq comp
            app.Radar1FreqNum.Value=app.Parameters.Signal.FrequencyOffset.Radar1/1e6;
            app.SliderFreqOffsetRadar1.Value=app.Parameters.Signal.FrequencyOffset.Radar1/1e6;
            app.Radar2FreqNum.Value=app.Parameters.Signal.FrequencyOffset.Radar2/1e6;
            app.SliderFreqOffsetRadar2.Value=app.Parameters.Signal.FrequencyOffset.Radar2/1e6;
            app.ABIFreqNum.Value=app.Parameters.Signal.FrequencyOffset.ABI/1e6;
            app.SliderFreqOffsetABI.Value=app.Parameters.Signal.FrequencyOffset.ABI/1e6;
            app.LTE1FreqNum.Value=app.Parameters.Signal.FrequencyOffset.LTE1/1e6;
            app.SliderFreqOffsetLTE1.Value=app.Parameters.Signal.FrequencyOffset.LTE1/1e6;
            app.LTE2FreqNum.Value=app.Parameters.Signal.FrequencyOffset.LTE2/1e6;
            app.SliderFreqOffsetLTE2.Value=app.Parameters.Signal.FrequencyOffset.LTE2/1e6;
            %% Inlitlialize Gain
            app.TargetSIRdBEdt.Value=app.Parameters.Signal.TargetSIR;
            setInterfaceGainsFromParameters(app)
            
            %% Initiate Switches and Status LEDs
            app.SwitchRadar1.Items={'',''};
            app.SwitchRadar1.ItemsData={'Off','On'};
            app.SwitchRadar2.Items={'',''};
            app.SwitchRadar2.ItemsData={'Off','On'};
            app.SwitchABI.Items={'',''};
            app.SwitchABI.ItemsData={'Off','On'};
            app.SwitchLTE1.Items={'',''};
            app.SwitchLTE1.ItemsData={'Off','On'};
            app.SwitchLTE2.Items={'',''};
            app.SwitchLTE2.ItemsData={'Off','On'};
           % tst=utilFun.logic2Enable(app.Parameters.Signal.Status.Radar1);
           % assignin('base','tst',tst)
            app.SwitchRadar1.Value=utilFun.logic2Enable(app.Parameters.Signal.Status.Radar1);
            app.SwitchRadar2.Value=utilFun.logic2Enable(app.Parameters.Signal.Status.Radar2);
            app.SwitchABI.Value=utilFun.logic2Enable(app.Parameters.Signal.Status.ABI);
            app.SwitchLTE1.Value=utilFun.logic2Enable(app.Parameters.Signal.Status.LTE1);
            app.SwitchLTE2.Value=utilFun.logic2Enable(app.Parameters.Signal.Status.LTE2);
            app.Radar1LED.Enable = utilFun.logic2Enable(app.Parameters.Signal.Status.Radar1);
            app.Radar2LED.Enable = utilFun.logic2Enable(app.Parameters.Signal.Status.Radar2);
            app.ABILED.Enable = utilFun.logic2Enable(app.Parameters.Signal.Status.ABI);
            app.LTE1LED.Enable = utilFun.logic2Enable(app.Parameters.Signal.Status.LTE1);
            app.LTE2LED.Enable = utilFun.logic2Enable(app.Parameters.Signal.Status.LTE2);
            
            app.LTEChannelState.Value=app.Parameters.Signal.LTEChannelStatus;
            app.ChannelTypeLTE1.Enable=utilFun.logic2Enable(app.Parameters.Signal.LTEChannelStatus);
            app.ChannelTypeLTE2.Enable=utilFun.logic2Enable(app.Parameters.Signal.LTEChannelStatus);
            
            app.AWGN.Value=app.Parameters.Signal.AWGNStatus;
            %% Initialize Preview Parameters
            app.TotalPreviewTime.Value=app.Parameters.Preview.TotalPreviewTime;
            app.SegmentPreviewTime.Value=app.Parameters.Preview.SegmentPreviewTime;
            app.ForwardRadar1.Value=app.Parameters.Preview.Forward.Radar1;
            app.ForwardRadar2.Value=app.Parameters.Preview.Forward.Radar2;
            app.ForwardABI.Value=app.Parameters.Preview.Forward.ABI;
            app.ForwardLTE1.Value=app.Parameters.Preview.Forward.LTE1;
            app.ForwardLTE2.Value=app.Parameters.Preview.Forward.LTE2;
            
            app.GainMethod.Items={'Power Levels','Target SIR'};
            app.GainMethod.Value=app.Parameters.Signal.GainMethod;
            GainMethodValueChanged(app);% call button pressed callback function 
            app.RadarPeakPowerEdt.Value=app.Parameters.Signal.PowerLevels_dBm.RadarPeakPower;
            app.LTEPowerEdt.Value=app.Parameters.Signal.PowerLevels_dBm.LTEPower;
            app.ABIPowerEdt.Value=app.Parameters.Signal.PowerLevels_dBm.ABIPower;
            app.AWGNPSDEdt.Value=app.Parameters.Signal.PowerLevels_dBm.AWGNPSD;
            
        end
        
        
        function loadSpectrumAnalyzer(app)
            %% Initialize Spectrum Analyzer
            fldnms=fieldnames(app.Parameters.SpectrumAnalyzer);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}))
                    app.hSpecAn.(fldnms{fldnm_Ind}) = app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind}).';
                else
                    app.hSpecAn.(fldnms{fldnm_Ind}) = app.Parameters.SpectrumAnalyzer.(fldnms{fldnm_Ind});
                end
            end
            
        end
        
        function loadTimeScope(app)
            %% Initialize Time Scope
            fldnms=fieldnames(app.Parameters.TimeScope);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.TimeScope.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.TimeScope.(fldnms{fldnm_Ind}))
                    app.hTScope.(fldnms{fldnm_Ind}) = app.Parameters.TimeScope.(fldnms{fldnm_Ind}).';
                else
                    app.hTScope.(fldnms{fldnm_Ind}) = app.Parameters.TimeScope.(fldnms{fldnm_Ind});
                end
            end

        end
        
        function loadSpectrogram(app)
            fldnms=fieldnames(app.Parameters.Spectrogram);
            for fldnm_Ind=1:length(fldnms)
                if ~isrow(app.Parameters.Spectrogram.(fldnms{fldnm_Ind}))&& isnumeric(app.Parameters.Spectrogram.(fldnms{fldnm_Ind}))
                    app.hSpectr.(fldnms{fldnm_Ind}) = app.Parameters.Spectrogram.(fldnms{fldnm_Ind}).';
                else
                    app.hSpectr.(fldnms{fldnm_Ind}) = app.Parameters.Spectrogram.(fldnms{fldnm_Ind});
                end
            end
        end
               
        function setUpWaveformPlayer(app)
            if exist([app.SelectWaveformFilePlayer.Value(1:end-3),'json'],'file')==2
                txtSignal=fileread([app.SelectWaveformFilePlayer.Value(1:end-3),'json']);
                temp=jsondecode(txtSignal);
                %assignin('base','temp',temp);
                if isfield(temp,'writeScaleFactor')
                    app.SetdataScaleGainEdt.Value=1/temp.writeScaleFactor;
                end
                %assignin('base','txtSignal',txtSignal);
                % Simple json format
                txtSignal = regexprep(txtSignal,{'{','}',','},{'{\n','\n}',',\n'});
                
                app.WaveformInfo.Value=txtSignal;
                app.WaveformInfo.Visible='on';
                %curRtLtIndx=regexp(txtSignal, {'{','}'})
            else
                %app.SetdataScaleGainEdt.Value
                FileInfo=dir(app.SelectWaveformFilePlayer.Value);
                timeSecPerFile=(1/app.Fs)*FileInfo.bytes/4;
                app.WaveformInfo.Value=['Total process time in sec=',num2str(timeSecPerFile)];
            end
            app.waveformPlayer=signalFromFile;
            app.waveformPlayer.inputIQDirection='IQ';
            app.waveformPlayer=setReadScale(app.waveformPlayer,app.SetdataScaleGainEdt.Value);
            %samplesPerSegment=180*1024;
            %app.waveformPlayer=setSamplesPerSegment(app.waveformPlayer,app.playerSamplesPerSegment);
            app.waveformPlayer.samplesPerSegment=app.playerSamplesPerSegment;
            app.waveformPlayer=setInputFile(app.waveformPlayer,app.SelectWaveformFilePlayer.Value);
            app.waveformPlayer=initInputFile(app.waveformPlayer);
            seekPositionSamples=0;
            app.waveformPlayer=setSeekPositionSamples(app.waveformPlayer,seekPositionSamples);
        end

        function createWaveform(app)
            app.waveformPervGen=waveform(app.Fs);
            app.waveformPervGen.numRadarSignals=app.numRadarSignals;
            app.waveformPervGen.numLTESignals=app.numLTESignals;
            app.waveformPervGen.numABISignals=app.numABISignals;
            app.waveformPervGen.LTEChType=struct2cell(app.Parameters.Signal.LTEChannelType).';
            app.waveformPervGen=setupLTEChannel(app.waveformPervGen);
            app.waveformPervGen.targetSIR=app.Parameters.Signal.TargetSIR;
        end        
        
        function   setupWaveform(app,option)

            app.waveformPervGen=resetWaveformFiles(app.waveformPervGen);
            selectedFileSources=struct2cell(app.Parameters.Signal.Source.File);
            
            if  any(cellfun(@isempty,selectedFileSources))
                %                 length(app.SelectedRadarFiles.ItemsData)~=app.numRadarSignals || ...
                %                    length(app.SelectedABIFiles.ItemsData)~=app.numABISignals || ...
                %                    length(app.SelectedLTEFiles.ItemsData)~=app.numLTESignals
                
                uialert(app.ESCWaveGen,['Please Select ',num2str(app.numRadarSignals), ' Radar Files, ',...
                    num2str(app.numABISignals), ' ABI Files, and ',...
                    num2str(app.numLTESignals), ' LTE Files'],'Files not found');
            else
                switch option
                    case 'preview'
                        samplesPerSegment=round(app.Parameters.Preview.SegmentPreviewTime/(1/app.Fs));
                        radarSeekPositionSamples=[round(app.Parameters.Preview.Forward.Radar1/(1/app.Fs)),...
                            round(app.Parameters.Preview.Forward.Radar2/(1/app.Fs))];
                        ABISeekPositionSamples=round(app.Parameters.Preview.Forward.ABI/(1/app.Fs));
                        LTEseekPositionSamples=[round(app.Parameters.Preview.Forward.LTE2/(1/app.Fs)),...
                            round(app.Parameters.Preview.Forward.LTE2/(1/app.Fs))];
                    case 'generation'
                        samplesPerSegment=round(app.Parameters.Signal.SegmentProcessTime*app.Fs);
                        radarSeekPositionSamples=[0, 0];
                        ABISeekPositionSamples=0;
                        LTEseekPositionSamples=[0, 0];
                        setWaveformTimesFromParameters(app);
                end
                
                    app.waveformPervGen.samplesPerSegment=samplesPerSegment;
                    if ~app.Parameters.Signal.ManualRadarReadScale
                    app.waveformPervGen=setupRadarSignal(app.waveformPervGen,selectedFileSources(1:2),...
                        app.Parameters.Signal.Source.Path.radarMetaFile,radarSeekPositionSamples);
                    app.waveformPervGen=setupABISignal(app.waveformPervGen,selectedFileSources(3),...
                        app.Parameters.Signal.Source.Path.radarMetaFile,ABISeekPositionSamples);
                    else
                    app.waveformPervGen=setupRadarSignal(app.waveformPervGen,selectedFileSources(1:2),...
                        app.Parameters.Signal.Source.Path.radarMetaFile,radarSeekPositionSamples,app.Parameters.Signal.RadarReadScale);
                    app.waveformPervGen=setupABISignal(app.waveformPervGen,selectedFileSources(3),...
                        app.Parameters.Signal.Source.Path.radarMetaFile,ABISeekPositionSamples,app.Parameters.Signal.RadarReadScale);
                    end
                    
                    app.waveformPervGen.measParameters.SIRWindow=app.Parameters.Signal.measParameters.SIRWindow;
                    app.waveformPervGen.measParameters.SIRBw=app.Parameters.Signal.measParameters.SIRBw;
                    
                    LTEReadScale=[app.Parameters.Signal.LTEReadScaleFactor app.Parameters.Signal.LTEReadScaleFactor];
                    app.waveformPervGen=setupLTESignal(app.waveformPervGen,selectedFileSources(4:5),LTEReadScale,LTEseekPositionSamples);
                    app.waveformPervGen.LTEChType=struct2cell(app.Parameters.Signal.LTEChannelType).';
                          
                    app.waveformPervGen=setupLTEChannel(app.waveformPervGen);
                    setWaveformStatusesFromParameters(app);
                    setWaveformFreqOffsetsFromParameters(app);
                    setWaveformGainsFromParameters(app);
                    app.waveformPervGen.errorColl=[];
                    app.waveformPervGen.writeScaleFactor=app.Parameters.Signal.WriteScaleFactor;
                    assignin('base','waveformPervGen',app.waveformPervGen);
            end
        end
        
        function setFileSourcesFromSelectedFiles(app,option)
            switch option
                case 'Radar'
                    app.Parameters.Signal.Source.File.Radar1=app.SelectedRadarFiles.ItemsData{1};
                    app.Parameters.Signal.Source.File.Radar2=app.SelectedRadarFiles.ItemsData{2};
                case 'ABI'
                    app.Parameters.Signal.Source.File.ABI=app.SelectedABIFiles.ItemsData{1};
                case 'LTE'
                    app.Parameters.Signal.Source.File.LTE1=app.SelectedLTEFiles.ItemsData{1};
                    app.Parameters.Signal.Source.File.LTE2=app.SelectedLTEFiles.ItemsData{2};
            end
        end
        
        function setWaveformStatusesFromParameters(app)
            app.waveformPervGen.LTEChState=app.Parameters.Signal.LTEChannelStatus;
            app.waveformPervGen.AWGNStatus=app.Parameters.Signal.AWGNStatus;
            app.waveformPervGen.radarStatus=[app.Parameters.Signal.Status.Radar1 app.Parameters.Signal.Status.Radar2];
            app.waveformPervGen.LTEStatus=[app.Parameters.Signal.Status.LTE1  app.Parameters.Signal.Status.LTE2];
            app.waveformPervGen.ABIStatus=app.Parameters.Signal.Status.ABI;
        end
        
        function setWaveformFreqOffsetsFromParameters(app)
            app.waveformPervGen.radarFreqOffset=[app.Parameters.Signal.FrequencyOffset.Radar1 app.Parameters.Signal.FrequencyOffset.Radar2];
            app.waveformPervGen.LTEFreqOffset=[app.Parameters.Signal.FrequencyOffset.LTE1 app.Parameters.Signal.FrequencyOffset.LTE2];
            app.waveformPervGen.ABIFreqOffset=app.Parameters.Signal.FrequencyOffset.ABI;
        end
        
        function setWaveformGainsFromParameters(app)
            app.waveformPervGen.radarGain=[app.Parameters.Signal.Gain.Radar1 app.Parameters.Signal.Gain.Radar2];
            app.waveformPervGen.LTEGain=[app.Parameters.Signal.Gain.LTE1 app.Parameters.Signal.Gain.LTE2];
            app.waveformPervGen.ABIGain=app.Parameters.Signal.Gain.ABI;
            app.waveformPervGen.AWGNVar=app.Parameters.Signal.AWGNVariance;
        end
        
        function setWaveformTimesFromParameters(app) 
        app.waveformPervGen.totalTime=app.Parameters.Signal.TotalProcessTime;
        app.waveformPervGen.radarStartTime=[app.Parameters.Signal.StartTime.Radar1 app.Parameters.Signal.StartTime.Radar2];
        app.waveformPervGen.LTEStartTime=[app.Parameters.Signal.StartTime.LTE1 app.Parameters.Signal.StartTime.LTE2];
        app.waveformPervGen.ABIStartTime=app.Parameters.Signal.StartTime.ABI;
        end
            
        function setInterfaceGainsFromParameters(app)
            app.GainRadar1Num.Value=app.Parameters.Signal.Gain.Radar1;
            app.KnobGainRadar1.Value=app.Parameters.Signal.Gain.Radar1;
            app.GainRadar2Num.Value=app.Parameters.Signal.Gain.Radar2;
            app.KnobGainRadar2.Value=app.Parameters.Signal.Gain.Radar2;
            app.GainABINum.Value=app.Parameters.Signal.Gain.ABI;
            app.KnobGainABI.Value=app.Parameters.Signal.Gain.ABI;
            app.GainLTE1Num.Value=app.Parameters.Signal.Gain.LTE1;
            app.KnobGainLTE1.Value=app.Parameters.Signal.Gain.LTE1;
            app.GainLTE2Num.Value=app.Parameters.Signal.Gain.LTE2;
            app.KnobGainLTE2.Value=app.Parameters.Signal.Gain.LTE2;
            
            app.AWGNVar.Value=app.Parameters.Signal.AWGNVariance;
            app.AWGNVarNum.Value=app.Parameters.Signal.AWGNVariance;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.currDir=pwd;
            %% Defaults
            app.appDir='..\app';
            %app.utilDir='..\util';
            app.dspDir='..\dsp';
            app.configDir='..\config';
            app.resDir='..\res';
            app.utilDir='..\util';
            
            if ~(ismcc || isdeployed)
                appPath = mfilename('fullpath');
                [progDir,~,~] = fileparts(appPath);
                cd(progDir);
                addpath(app.appDir);
                addpath(app.dspDir);
                addpath(app.configDir);
                addpath(app.resDir);
                addpath(app.utilDir);
            end
            
            imDat = imread(fullfile(app.resDir,'CTL_logo.png'),'png');
            set(app.LogoImage,'Box','off','XGrid','off', 'YGrid','off','XTick',[],'YTick',[]);
            app.LogoImage.XAxis.Visible='off';
            app.LogoImage.YAxis.Visible='off';
            
            %set(app.LogoImage,'Axis', 'off');
            %imagesc(app.LogoImage,C);
            imshow(imDat,'Parent',app.LogoImage);
            
            app.folderBrowser=folderBrowserGUI;
            loadDefaults(app);
            app.hSpecAn = dsp.SpectrumAnalyzer;
            app.hSpecAn.SampleRate =app.Fs;
            app.hTScope= dsp.TimeScope;
            app.hTScope.SampleRate=app.Fs;
            app.hSpectr= dsp.SpectrumAnalyzer;
            app.hSpectr.SampleRate =app.Fs;
            
            loadSpectrumAnalyzer(app);
            loadTimeScope(app);
            loadSpectrogram(app);
            
            
        end

        % Value changed function: ABIFreqNum
        function ABIFreqNumValueChanged(app, event)
            app.Parameters.Signal.FrequencyOffset.ABI=app.ABIFreqNum.Value*1e6;
            app.SliderFreqOffsetABI.Value =app.Parameters.Signal.FrequencyOffset.ABI/1e6;
        end

        % Value changed function: ABIPowerEdt
        function ABIPowerEdtValueChanged(app, event)
            app.Parameters.Signal.PowerLevels_dBm.ABIPower= app.ABIPowerEdt.Value; 
        end

        % Value changed function: AWGNPSDEdt
        function AWGNPSDEdtValueChanged(app, event)
            app.Parameters.Signal.PowerLevels_dBm.AWGNPSD = app.AWGNPSDEdt.Value;
        end

        % Value changed function: AWGN
        function AWGNValueChanged(app, event)
            app.Parameters.Signal.AWGNStatus = app.AWGN.Value;
            
        end

        % Value changed function: AWGNVarNum
        function AWGNVarNumValueChanged(app, event)
            app.Parameters.Signal.AWGNVariance = app.AWGNVarNum.Value;
            app.AWGNVar.Value=app.Parameters.Signal.AWGNVariance;
        end

        % Value changing function: AWGNVar
        function AWGNVarValueChanging(app, event)
            app.Parameters.Signal.AWGNVariance = event.Value;
            app.AWGNVarNum.Value=app.Parameters.Signal.AWGNVariance;
        end

        % Value changed function: ChannelTypeLTE1
        function ChannelTypeLTE1ValueChanged(app, event)
            app.Parameters.Signal.LTEChannelType.LTE1 = app.ChannelTypeLTE1.Value;
            app.waveformPervGen.LTEChType(1)={app.Parameters.Signal.LTEChannelType.LTE1};
            app.waveformPervGen=setupLTEChannel(app.waveformPervGen);
        end

        % Value changed function: ChannelTypeLTE2
        function ChannelTypeLTE2ValueChanged(app, event)
            app.Parameters.Signal.LTEChannelType.LTE2 = app.ChannelTypeLTE2.Value;
            app.waveformPervGen.LTEChType(2)={app.Parameters.Signal.LTEChannelType.LTE2};
            app.waveformPervGen=setupLTEChannel(app.waveformPervGen);
        end

        % Selection changed function: DisplayPlayer
        function DisplayPlayerSelectionChanged(app, event)
            
              DisplayPlayerSelect= app.DisplayPlayer.SelectedObject;
            switch  DisplayPlayerSelect.Text
                case 'Frequency'
                    app.DisplayPlayState=1;
                    app.hTScope.hide;
                    app.hSpectr.hide;
                    %loadSpectrumAnalyzer(app);
                    app.hSpecAn.show;
                case 'Time'
                    app.DisplayPlayState=2;
                    app.hSpecAn.hide;
                    app.hSpectr.hide;
                    app.hTScope.show;
                case 'Spectrogram'
                    app.DisplayPlayState=3;
                    app.hTScope.hide;
                    %setSpectAsSpectrogram(app);
                    app.hSpecAn.hide;
                    app.hSpectr.show;
            end
            
            
        end

        % Selection changed function: DisplayPreview
        function DisplayPreviewSelectionChanged(app, event)
            DisplaySelect= app.DisplayPreview.SelectedObject;
            switch DisplaySelect.Text
                case 'Frequency'
                    app.DisplayPreviewState=1;
                    app.hTScope.hide;
                    app.hSpectr.hide;
                    app.hSpecAn.show;
                case 'Time'
                    app.DisplayPreviewState=2;
                    app.hSpecAn.hide;
                    app.hSpectr.hide;
                    app.hTScope.show;
                case 'Spectrogram'
                    app.DisplayPreviewState=3;
                    app.hTScope.hide;
                    app.hSpecAn.hide;
                    app.hSpectr.show;
            end
            %app.DebugLbl.Text=num2str(app.DisplayPreviewState);
        end

        % Close request function: ESCWaveGen
        function ESCWaveGenCloseRequest(app, event)
                      
            closeTxt=sprintf('Make sure to stop waveform preview/play/generation before closing! \n Confirm to close');
            selection = questdlg(closeTxt,...
                'Close ESC Waveform Generator?',...
                'Yes','No','Yes');
            switch selection
                case 'Yes'
                    %app.StopPlayState=1;
                    %pause(2);
                    %app.hSpecAn.hide();
                    %app.hTScope.hide();
                    %clear app.hSpecAn
                    %clear app.hTScope
                    %pause(2)
                    delete(app)
                case 'No'
                    return
            end
            
        end

        % Button pushed function: EstimateGainParametersButton
        function EstimateGainParametersButtonPushed(app, event)
            if ~isReady(app.waveformPervGen)
                setupWaveform(app,'preview');
            end
            switch app.Parameters.Signal.GainMethod
                case 'Power Levels'
                   app.waveformPervGen.PowerLevels_dBm.RadarPeakPower=app.Parameters.Signal.PowerLevels_dBm.RadarPeakPower;
                   app.waveformPervGen.PowerLevels_dBm.LTEPower=app.Parameters.Signal.PowerLevels_dBm.LTEPower;
                   app.waveformPervGen.PowerLevels_dBm.ABIPower=app.Parameters.Signal.PowerLevels_dBm.ABIPower;
                   app.waveformPervGen.PowerLevels_dBm.AWGNPSD=app.Parameters.Signal.PowerLevels_dBm.AWGNPSD;
                case 'Target SIR'
                app.waveformPervGen.targetSIR=app.Parameters.Signal.TargetSIR;   
            end
            
            try
            %app.waveformPervGen.gainEstimateMethod=   
            app.waveformPervGen=estimateGains(app.waveformPervGen);
            
            app.Parameters.Signal.Gain.Radar1=app.waveformPervGen.radarGain(1);
            app.Parameters.Signal.Gain.Radar2=app.waveformPervGen.radarGain(2);
            app.Parameters.Signal.Gain.ABI=app.waveformPervGen.ABIGain;
            app.Parameters.Signal.Gain.LTE1=app.waveformPervGen.LTEGain(1);
            app.Parameters.Signal.Gain.LTE2=app.waveformPervGen.LTEGain(2);
            app.Parameters.Signal.AWGNVariance=app.waveformPervGen.AWGNVar;
            app.Parameters.Signal.WriteScaleFactor=app.waveformPervGen.writeScaleFactor;
            %assignin('base','ParametersEst',app.Parameters)
            assignin('base','waveformPervGenEst',app.waveformPervGen);
            %setupWaveform(app);
            setInterfaceGainsFromParameters(app);
            %assignin('base','waveformPervGenEst',app.waveformPervGen);
            assignin('base','ParametersEst',app.Parameters)
            catch msg
                uialert(app.ESCWaveGen,msg.message,'Error:');
            end
        end

        % Value changed function: ForwardABI
        function ForwardABIValueChanged(app, event)
            app.Parameters.Preview.Forward.ABI = app.ForwardABI.Value;
            
        end

        % Value changed function: ForwardLTE1
        function ForwardLTE1ValueChanged(app, event)
            app.Parameters.Preview.Forward.LTE1 = app.ForwardLTE1.Value;
            
        end

        % Value changed function: ForwardLTE2
        function ForwardLTE2ValueChanged(app, event)
            app.Parameters.Preview.Forward.LTE2 = app.ForwardLTE2.Value;
            
        end

        % Value changed function: ForwardRadar1
        function ForwardRadar1ValueChanged(app, event)
            app.Parameters.Preview.Forward.Radar1= app.ForwardRadar1.Value;
            
        end

        % Value changed function: ForwardRadar2
        function ForwardRadar2ValueChanged(app, event)
            app.Parameters.Preview.Forward.Radar2= app.ForwardRadar2.Value;
            
        end

        % Value changed function: GainABINum
        function GainABINumValueChanged(app, event)
            app.Parameters.Signal.Gain.ABI = app.GainABINum.Value;
            app.KnobGainABI.Value=app.Parameters.Signal.Gain.ABI;
            
        end

        % Value changed function: GainLTE1Num
        function GainLTE1NumValueChanged(app, event)
            app.Parameters.Signal.Gain.LTE1 = app.GainLTE1Num.Value;
            app.KnobGainLTE1.Value=app.Parameters.Signal.Gain.LTE1;
            
        end

        % Value changed function: GainLTE2Num
        function GainLTE2NumValueChanged(app, event)
            app.Parameters.Signal.Gain.LTE2 = app.GainLTE2Num.Value;
            app.KnobGainLTE2.Value=app.Parameters.Signal.Gain.LTE2;
        end

        % Selection changed function: GainMethodGen
        function GainMethodGenSelectionChanged(app, event)
            app.Parameters.Signal.GainMethod = app.GainMethodGen.SelectedObject.Text;
            
        end

        % Value changed function: GainMethod
        function GainMethodValueChanged(app, event)
            app.Parameters.Signal.GainMethod= app.GainMethod.Value;
            app.waveformPervGen.gainEstimateMethod=app.Parameters.Signal.GainMethod;
            switch app.Parameters.Signal.GainMethod
                case 'Power Levels'
                    app.RadarPeakPowerEdt.Enable='on';
                    app.LTEPowerEdt.Enable='on';
                    app.ABIPowerEdt.Enable='on';
                    app.AWGNPSDEdt.Enable='on';
                    app.TargetSIRdBEdt.Enable='off';
                case 'Target SIR'
                    app.RadarPeakPowerEdt.Enable='off';
                    app.LTEPowerEdt.Enable='off';
                    app.ABIPowerEdt.Enable='off';
                    app.AWGNPSDEdt.Enable='off';
                    app.TargetSIRdBEdt.Enable='on';
            end   
        end

        % Value changed function: GainRadar1Num
        function GainRadar1NumValueChanged(app, event)
            app.Parameters.Signal.Gain.Radar1 = app.GainRadar1Num.Value;
            app.KnobGainRadar1.Value=app.Parameters.Signal.Gain.Radar1;
        end

        % Value changed function: GainRadar2Num
        function GainRadar2NumValueChanged(app, event)
            app.Parameters.Signal.Gain.Radar2 = app.GainRadar2Num.Value;
            app.KnobGainRadar2.Value=app.Parameters.Signal.Gain.Radar2;
            
        end

        % Button pushed function: GenerateThisWaveform
        function GenerateThisWaveformButtonPushed(app, event)
            InterfaceObj=findobj(app.ESCWaveGen,'Enable','on');
            set(InterfaceObj,'Enable','off');
            
            try
                createWaveform(app);
                %setWaveformTimesFromParameters(app);
                setupWaveform(app,'generation');
                %Todo address the case of no peak files
                %app.waveformPervGen=estimateGainsFromTargetSIR(app.waveformPervGen);
                
                app.waveformPervGen=setupWaveformToFile(app.waveformPervGen,app.Parameters.Signal.Destination.WaveformFile);
                % assignin('base','waveformPervGenTest',app.waveformPervGen);
                app.SingleFileGenIndlbl.Text=['Generating waveform...'];
                tic;
                [app.waveformPervGen]=generateFullWaveform(app.waveformPervGen,app.Parameters.Signal.ShortFile);
                
                execTime=toc;
                app.SingleFileGenIndlbl.Text=['Ready, Last finished in ',utilFun.sec2DurationChar(execTime)];
            catch msg
                %assignin('base','msg',msg);
                uialert(app.ESCWaveGen,msg.message,'Error: Please check waveform parameters');
            end
            assignin('base','waveformPervGen1',app.waveformPervGen);
            set(InterfaceObj,'Enable','on');
            
        end

        % Button pushed function: GenerateWaveforms
        function GenerateWaveformsButtonPushed(app, event)
            %assignin('base','genWaveformsPar',app.Parameters);
            fieldNames={'Signal';'SignalMulti'};
            for I=1:length(fieldNames)
                genParameters.(fieldNames{I})=app.Parameters.(fieldNames{I});
            end
            numSignals={'numRadarSignals'; 'numLTESignals'; 'numABISignals'};
            for I=1:length(numSignals)
                genParameters.Signal.(numSignals{I})=app.(numSignals{I});
            end
            
            assignin('base','genParameters',genParameters);
            waveformMultiGen=waveformGenerator(genParameters.SignalMulti.NumberOfFiles);
            waveformMultiGen.useParallel=genParameters.SignalMulti.UseParallel;
            assignin('base','waveformMultiGen',waveformMultiGen);
            if utilFun.enable2Logic(genParameters.SignalMulti.UseParallel)
            waveformMultiGen.NumWorkers=genParameters.SignalMulti.NumberOfWorkers;
            end
            %assignin('base','waveformMultiGen',waveformMultiGen);
            
            %app.SetNumberofWorkers.Enable='off';
            InterfaceObj=findobj(app.ESCWaveGen,'Enable','on');
            set(InterfaceObj,'Enable','off');
            
            
            try
                tic;
                app.MultiFileGenIndlbl.Text='Starting Parallel Computing Engine...';
                if  utilFun.enable2Logic(genParameters.SignalMulti.UseParallel)
                    waveformMultiGen=initParallel(waveformMultiGen);
                    app.AvailableNumberofWorkers.Text=['Available Number of Workers=',num2str(waveformMultiGen.poolObj.NumWorkers)];
                end
                
                app.MultiFileGenIndlbl.Text='Initializing waveforms...';
                waveformMultiGen=initWaveformsMap(waveformMultiGen,genParameters);
                app.MultiFileGenIndlbl.Text='Generating waveforms...';
                waveformMultiGen=runGenerator(waveformMultiGen);
                
                assignin('base','waveformMultiGen',waveformMultiGen);
                execTime=toc;
                app.MultiFileGenIndlbl.Text=['Multi-generation Ready, Last finished in ',utilFun.sec2DurationChar(execTime)];
            catch msg
                uialert(app.ESCWaveGen,msg.message,'Error: On Multi-generation process');
                app.Parameters.SignalMulti.UseParallel='Off';
                app.ParallelProcess.Value=utilFun.enable2Logic(genParameters.SignalMulti.UseParallel);
                %app.SetNumberofWorkers.Enable='on';
                app.MultiFileGenIndlbl.Text=['Multi-generation process did not finish correctly'];
            end
            %assignin('base','poolobjApp',poolobj)
            set(InterfaceObj,'Enable','on');
            %app.MultiFileGenIndlbl.Text='Ready';
            
            %app.SetNumberofWorkers.Enable='on';
            
           
        end

        % Value changing function: KnobGainABI
        function KnobGainABIValueChanging(app, event)
            app.Parameters.Signal.Gain.ABI=event.Value;
            app.GainABINum.Value= app.Parameters.Signal.Gain.ABI;
            
        end

        % Value changing function: KnobGainLTE1
        function KnobGainLTE1ValueChanging(app, event)
            app.Parameters.Signal.Gain.LTE1=event.Value;
            app.GainLTE1Num.Value= app.Parameters.Signal.Gain.LTE1;
            
        end

        % Value changing function: KnobGainLTE2
        function KnobGainLTE2ValueChanging(app, event)
            app.Parameters.Signal.Gain.LTE2=event.Value;
            app.GainLTE2Num.Value= app.Parameters.Signal.Gain.LTE2;
        end

        % Value changing function: KnobGainRadar1
        function KnobGainRadar1ValueChanging(app, event)
            app.Parameters.Signal.Gain.Radar1=event.Value;
            app.GainRadar1Num.Value= app.Parameters.Signal.Gain.Radar1;
        end

        % Value changing function: KnobGainRadar2
        function KnobGainRadar2ValueChanging(app, event)
            app.Parameters.Signal.Gain.Radar2=event.Value;
            app.GainRadar2Num.Value= app.Parameters.Signal.Gain.Radar2;
            
        end

        % Value changed function: LTE1FreqNum
        function LTE1FreqNumValueChanged(app, event)
            app.Parameters.Signal.FrequencyOffset.LTE1 = app.LTE1FreqNum.Value*1e6;
            app.SliderFreqOffsetLTE1.Value=app.Parameters.Signal.FrequencyOffset.LTE1/1e6;
        end

        % Value changed function: LTE2FreqNum
        function LTE2FreqNumValueChanged(app, event)
            app.Parameters.Signal.FrequencyOffset.LTE2= app.LTE2FreqNum.Value*1e6;
            app.SliderFreqOffsetLTE2.Value=app.Parameters.Signal.FrequencyOffset.LTE2/1e6;
        end

        % Value changed function: LTEChannelState
        function LTEChannelStateValueChanged(app, event)
            value=app.LTEChannelState.Value;
            app.Parameters.Signal.LTEChannelStatus = value;
            app.ChannelTypeLTE1.Enable=utilFun.logic2Enable(value);
            app.ChannelTypeLTE2.Enable=utilFun.logic2Enable(value);
            
        end

        % Value changed function: LTEPowerEdt
        function LTEPowerEdtValueChanged(app, event)
           app.Parameters.Signal.PowerLevels_dBm.LTEPower = app.LTEPowerEdt.Value;
        end

        % Value changed function: LTEReadScaleEdt
        function LTEReadScaleEdtValueChanged(app, event)
            app.Parameters.Signal.LTEReadScaleFactor = app.LTEReadScaleEdt.Value;
            
        end

        % Button pushed function: LoadDefaults
        function LoadDefaultsButtonPushed(app, event)
            try
                loadDefaults(app);
                %setUpWaveformPlayer(app);
            catch msg
                uialert(app.ESCWaveGen,msg.message,'Error: loading default settings');
            end
        end

        % Button pushed function: LoadPreviewParameters
        function LoadPreviewParametersButtonPushed(app, event)
            %assignin('base','par',app.Parameters);
            app.SetNumberofWorkers.Value=app.Parameters.SignalMulti.NumberOfWorkers;
            app.NumberOfFiles.Value=app.Parameters.SignalMulti.NumberOfFiles;
            app.WaveformFilePrefixedt.Value=app.Parameters.Signal.Destination.FileNamePrefix;
                        %% Initialize gen setting
            app.ParallelProcess.Value=utilFun.enable2Logic(app.Parameters.SignalMulti.UseParallel); 
            app.SaveWavefomAsedt.Value=app.Parameters.Signal.Destination.WaveformFile;
            app.SaveWavefomsDiredt.Value=app.Parameters.Signal.Destination.Path;
            app.RadarSignalsDirGen.Text= sprintf('%s\n%s','Radar Signals Dir:',app.Parameters.Signal.Source.Path.RadarDir);
            app.RadarMetaFileGen.Text=sprintf('%s\n%s','Radar Meta File:',app.Parameters.Signal.Source.Path.radarMetaFile);
            app.LTESignalsDirGen.Text=sprintf('%s\n%s','LTE Signals Dir:',app.Parameters.Signal.Source.Path.LTEDir);
            %app.SetNumberofWorkers.Enable='On';
            poolobj = gcp('nocreate'); % If no pool, do not create new one.
            if ~isempty(poolobj)
                app.AvailableNumberofWorkers.Text=['Available Number of Workers=',num2str(poolobj.NumWorkers)];
            end
            
            app.SingleFileFixedPar1.ColumnName={'Radar1','Radar2','ABI','LTE1','LTE2'};
            app.SingleFileFixedPar1.RowName={'Status';'Frequncy Offset';'Gain';'Start Time'};
            %app.SingleFileFixedPar1.Data={true,true,false;1,2,3;11,22,33};
            app.SingleFileFixedPar1.Data=[struct2cell(app.Parameters.Signal.Status).';...
                struct2cell(app.Parameters.Signal.FrequencyOffset).'; struct2cell(app.Parameters.Signal.Gain).';...
                struct2cell(app.Parameters.Signal.StartTime).'];
            app.SingleFileFixedPar1.ColumnEditable=[true,true,true,true,true];
            
            app.SingleFileFixedPar2.ColumnName={'Value'};
            app.SingleFileFixedPar2.RowName={'Total Process Time';'Short File';'Target SIR';'Segment Process Time';...
                'AWGN Status';'AWGN Variance';'Write Scale Factor';'Manual Radar Read Scale';'Radar Read Scale';...
                'LTE Read Scale Factor';'LTE Channel Status'};
            SignalCell=struct2cell(app.Parameters.Signal);
            app.SingleFileFixedPar2.Data=SignalCell(2:12);
            app.SingleFileFixedPar2.ColumnEditable=[true];
            app.SingleFileFixedParLTECh.ColumnName={'LTE1','LTE2'};
            app.SingleFileFixedParLTECh.ColumnFormat={app.ThreeGPPChTypes,app.ThreeGPPChTypes};
            % app.SingleFileFixedParLTECh.ColumnFormat={'EPA5Hz'    'EVA5Hz'    'EVA70Hz'    'ETU70Hz'    'ETU300Hz'};
            app.SingleFileFixedParLTECh.RowName={'Channel Type'};
            app.SingleFileFixedParLTECh.Data=struct2cell(app.Parameters.Signal.LTEChannelType)';
            %app.SingleFileFixedParLTECh.ColumnFormat={{'EPA5Hz'    'EVA5Hz'    'EVA70Hz'    'ETU70Hz'    'ETU300Hz'} {'EPA5Hz'    'EVA5Hz'    'EVA70Hz'    'ETU70Hz'    'ETU300Hz'}};
            app.SingleFileFixedParLTECh.ColumnEditable=[true];
            
            Tbl2Data11=app.SingleFileFixedPar2.Data(11);
            app.SingleFileFixedParLTECh.Enable=utilFun.logic2Enable(Tbl2Data11{1});
            
            app.PowerLevels.ColumnName={'Power dBm'};
            app.PowerLevels.RowName={'Radar Peak';'LTE';'ABI';'AWGN PSD'};
            app.PowerLevels.Data=struct2cell(app.Parameters.Signal.PowerLevels_dBm);
            %% Multi File Generation
            %Method=struct2cell(app.Parameters.SignalMulti.Method);
            
            app.MultiFileMethod.ColumnName={'Method'};
            app.MultiFileMethod.ColumnFormat={{'Fix','Random','Vary'}};
            app.MultiFileMethod.RowName={'Radar1 Start Time';'Radar2 Start Time';'ABI Start Time';...
                'Radar1 Frequency';'Radar2 Frequency';'ABI Frequency';'Target SIR';'LTE Channel Type'};
            app.MultiFileMethod.Data=struct2cell(app.Parameters.SignalMulti.Method);
            app.MultiFileMethod.ColumnEditable=[true];
            
            app.MultiFileBounds.ColumnName={'Lower Bound','Upper Bound'};
            %             app.MultiFileBounds.RowName={'Radar1 Start Time';'Radar2 Start Time';'ABI Start Time';'Radar1 Frequency Offset';...
            %                 'Radar2 Frequency Offset MHz';'ABI Frequency Offset MHz';'Target SIR'};
            app.MultiFileBounds.Data=cell2mat(struct2cell(app.Parameters.SignalMulti.Bounds).').';
            app.MultiFileBounds.ColumnEditable=[true];
            
            app.MultiFileStep.ColumnName={'Step Value'};
            %             app.MultiFileStep.RowName={'Radar1 Start Time';'Radar2 Start Time';'ABI Start Time';'Radar1 Frequency Offset';...
            %                 'Radar2 Frequency Offset MHz';'ABI Frequency Offset MHz';'Target SIR'};
            app.MultiFileStep.Data=struct2cell(app.Parameters.SignalMulti.Step);
            app.MultiFileStep.ColumnEditable=[true];
            %app.GainMethodGen.Value=app.Parameters.Signal.GainMethod;
            %app.GainMethodGen.SelectedObject.Text=app.Parameters.Signal.GainMethod;
            testGainMethod=ismember({'Target SIR';'Power Levels'},app.Parameters.Signal.GainMethod);
            for I=length(testGainMethod)
              app.GainMethodGen.Buttons(I).Value=testGainMethod(I);
            end
            %assignin('base','appGainMethodGen',app.GainMethodGen);
        end

        % Cell edit callback: MultiFileBounds
        function MultiFileBoundsCellEdit(app, event)
            fieldNames=strrep(app.MultiFileMethod.RowName(1:end-1), ' ', '');
            tempS=cell2struct(num2cell(app.MultiFileBounds.Data,2),fieldNames);
            
            for I=1:length(fieldNames)
                app.Parameters.SignalMulti.Bounds.(fieldNames{I})=tempS.(fieldNames{I}).';
            end
        end

        % Cell edit callback: MultiFileMethod
        function MultiFileMethodCellEdit(app, event)
            fieldNames=strrep(app.MultiFileMethod.RowName, ' ', '');
            tempS=cell2struct(app.MultiFileMethod.Data,fieldNames);
            
            for I=1:length(fieldNames)
                app.Parameters.SignalMulti.Method.(fieldNames{I})=tempS.(fieldNames{I});
            end
            
        end

        % Cell edit callback: MultiFileStep
        function MultiFileStepCellEdit(app, event)
            fieldNames=strrep(app.MultiFileMethod.RowName(1:end-1), ' ', '');
            tempS=cell2struct(app.MultiFileStep.Data,fieldNames);
            %             assignin('base','tempS',tempS);
            %             assignin('base','fieldNames',fieldNames);
            %             assignin('base','Parameterstbl6b4',app.Parameters); 
            for I=1:length(fieldNames)
                app.Parameters.SignalMulti.Step.(fieldNames{I})=tempS.(fieldNames{I});
            end
            assignin('base','Parameterstbl6Aft',app.Parameters);           
        end

        % Value changed function: NumberOfFiles
        function NumberOfFilesValueChanged(app, event)
            app.Parameters.SignalMulti.NumberOfFiles= app.NumberOfFiles.Value;
        end

        % Value changed function: ParallelProcess
        function ParallelProcessValueChanged(app, event)
            app.Parameters.SignalMulti.UseParallel =utilFun.logic2Enable(app.ParallelProcess.Value);        
        end

        % Button pushed function: PlayPlayer
        function PlayPlayerButtonPushed(app, event)
            try
                
                switch app.DisplayPlayState
                    case 1
                        app.hSpecAn.show;
                        app.hTScope.hide;
                        app.hSpectr.hide;
                    case 2
                        app.hSpecAn.hide;
                        app.hTScope.show;
                        app.hSpectr.hide;
                    case 3
                        app.hSpecAn.hide;
                        app.hTScope.hide;
                        app.hSpectr.show;
                end
                
                FileInfo = dir(app.SelectWaveformFilePlayer.Value);
                numSamplesPerFile=FileInfo.bytes/4;
                NLoop=floor(numSamplesPerFile/app.playerSamplesPerSegment);
                %app.hTScope.TimeSpan=app.playerSamplesPerSegment*1/app.Fs;
                %NLoop=100;
                I=0;
                %cdIfNotDepComp(app,app.dspDir);
                while (~(app.StopPlayState) && (I<NLoop))
                    
                    radarMeasData=readSamples(app.waveformPlayer);
                    app.waveformPlayer=seekNextPositionSamples(app.waveformPlayer);
                    %assignin('base','radarMeasData',radarMeasData)
                    if ~isempty(radarMeasData)
            
                        switch app.DisplayPlayState
                            case 1
                                app.hSpecAn(radarMeasData);
                            case 2
                                app.hTScope(abs(radarMeasData));
                            case 3
                                app.hSpectr(radarMeasData);
                        end
                        
                    else
                        app.StopPlayState=1;
                        seekPositionSamples=0;
                        app.waveformPlayer=setSeekPositionSamples(app.waveformPlayer,seekPositionSamples);
                    end
                    I=I+1;
                end
                app.StopPlayState=0;
            catch msg
               uialert(app.ESCWaveGen,msg.message,'Error: Waveform player');
            end
        end

        % Cell edit callback: PowerLevels
        function PowerLevelsCellEdit(app, event)
            
        end

        % Button pushed function: Preview
        function PreviewButtonPushed(app, event)
            
            switch app.DisplayPreviewState
                case 1
                    app.hSpecAn.show;
                    app.hTScope.hide;
                    app.hSpectr.hide;
                case 2
                    app.hSpecAn.hide;
                    app.hTScope.show;
                    app.hSpectr.hide;
                case 3
                    app.hSpecAn.hide;
                    app.hTScope.hide;
                    app.hSpectr.show;
            end
            
            try                  
                setupWaveform(app,'preview');
                %app.waveformPervGen.saveScaleFactor=app.SaveScaleFactor.Value;
                
                Ts=1/app.Fs;
                NTot=floor(app.Parameters.Preview.TotalPreviewTime/Ts);
                NLoop=floor(NTot/app.waveformPervGen.samplesPerSegment);
                I=0;
                t0=0;
                %if app.DisplayPreviewState==2
                %    app.hTScope.TimeSpan=app.Parameters.Preview.SegmentPreviewTime;
                %end
                app.ReadyStateLbl.Text='Running...';
                app.ReadyStateLbl.FontColor=[1.00,0.00,0.00];
                app.SIRLbl.Text='';
                meanMinMaxpks2=zeros(app.waveformPervGen.numRadarSignals,3);
                SIRdB=pow2db(zeros(app.waveformPervGen.numRadarSignals,3));
                if ~isempty(app.waveformPervGen.SIRData) && isfield(app.waveformPervGen.SIRData,'original')
                    for I=1:app.waveformPervGen.numRadarSignals
                        meanMinMaxpks2(I,1)=mean(app.waveformPervGen.SIRData(I).original.pks);
                        meanMinMaxpks2(I,2)=min(app.waveformPervGen.SIRData(I).original.pks);
                        meanMinMaxpks2(I,3)=max(app.waveformPervGen.SIRData(I).original.pks);
                    end
                end
                SIRtxt{1,1}='mean= ';SIRtxt{2,1}='min= ';SIRtxt{3,1}='max= ';
                while (~(app.StopSimState) && (I<NLoop))
                    I=I+1;
                    
                    setWaveformStatusesFromParameters(app);
                    
                    setWaveformFreqOffsetsFromParameters(app);
                    
                    setWaveformGainsFromParameters(app);
                    
                    Bw=2e6;
                    bndPowrStatus=app.ShowSIR.Value;
                    [app.waveformPervGen,t0,interfBndPowr]=generateWaveformSegment(app.waveformPervGen,t0,bndPowrStatus,Bw);
                    switch app.DisplayPreviewState
                        case 1
                            app.hSpecAn(app.waveformPervGen.signalOut)
                        case 2
                            app.hTScope(abs(app.waveformPervGen.signalOut))
                        case 3
                            app.hSpectr(app.waveformPervGen.signalOut)
                    end
                    
                    if app.ShowSIR.Value
            
                        SIRdB=10*log10((((app.waveformPervGen.radarGain.').*meanMinMaxpks2).^2)./interfBndPowr);
                        app.SIRLbl.Text=sprintf('mean=%0.2f, %0.2f \n min=%0.2f, %0.2f \n max=%0.2f, %0.2f',SIRdB);
                        %{cat(2,char(SIRtxt),num2str(SIRdB.'))};
                    end
                end
                
            catch msg
                uialert(app.ESCWaveGen,msg.message,'Error: Waveform Preview');
            end


            %% Go back to initial state
            
            app.StopSimState=0;
            app.ReadyStateLbl.Text='Ready...';
            app.ReadyStateLbl.FontColor=[0.07,0.53,0.07];
            
        end

        % Value changed function: Radar1FreqNum
        function Radar1FreqNumValueChanged(app, event)
            app.Parameters.Signal.FrequencyOffset.Radar1=app.Radar1FreqNum.Value*1e6;
            app.SliderFreqOffsetRadar1.Value =app.Parameters.Signal.FrequencyOffset.Radar1/1e6;
            
        end

        % Value changed function: Radar2FreqNum
        function Radar2FreqNumValueChanged(app, event)
            app.Parameters.Signal.FrequencyOffset.Radar2=app.Radar2FreqNum.Value*1e6;
            app.SliderFreqOffsetRadar2.Value =app.Parameters.Signal.FrequencyOffset.Radar2/1e6;
        end

        % Value changed function: RadarFiles
        function RadarFilesValueChanged(app, event)
            %app.fileNo = app.RadarFiles.Value;
        end

        % Value changed function: RadarPeakPowerEdt
        function RadarPeakPowerEdtValueChanged(app, event)
            app.Parameters.Signal.PowerLevels_dBm.RadarPeakPower= app.RadarPeakPowerEdt.Value;
        end

        % Value changed function: RadarReadScaleEdt
        function RadarReadScaleEdtValueChanged(app, event)
            app.Parameters.Signal.RadarReadScale = app.RadarReadScaleEdt.Value;
        end

        % Button pushed function: ResetSpectrumAnalyzerandTimeSope
        function ResetSpectrumAnalyzerandTimeSopeButtonPushed(app, event)
            try
                app.hSpecAn.hide;
                app.hTScope.hide;
                app.hSpectr.hide;
                
                %app.hSpecAn = dsp.SpectrumAnalyzer;
                %app.hSpecAn.SampleRate =app.Fs;
                %app.hTScope= dsp.TimeScope;
                %app.hTScope.SampleRate=app.Fs;
                %app.hSpectr = dsp.SpectrumAnalyzer;
                %app.hSpectr.SampleRate =app.Fs;
                reset(app.hSpecAn);
                reset(app.hTScope);
                reset(app.hSpectr);
                app.DisplayPlayState=1;
                app.DisplayPreviewState=1;
                app.FrequencyPlayer.Value=true;
                app.Frequency.Value=true;
                loadSpectrumAnalyzer(app);
                loadTimeScope(app);
                loadSpectrogram(app)
            catch msg
                uialert(app.ESCWaveGen,msg.message,'Error: Can"t reset spectrum anaylyzer and time scope');
            end
        end

        % Value changed function: SaveWavefomAsedt
        function SaveWavefomAsedtValueChanged(app, event)
            app.Parameters.Signal.Destination.WaveformFile = app.SaveWavefomAsedt.Value;           
        end

        % Value changed function: SaveWavefomsDiredt
        function SaveWavefomsDiredtValueChanged(app, event)
            app.Parameters.Signal.Destination.Path = app.SaveWavefomsDiredt.Value;
        end

        % Button pushed function: SaveWaveformAs
        function SaveWaveformAsButtonPushed(app, event)
            [WaveformSavefile, WaveformSavepath] = uiputfile(...
                {'*.dat';'*.*'},...
                'Save waveform as','D:\Spectrum-Share');
            if (sum(WaveformSavefile~=0) && sum(WaveformSavepath~=0))
                app.Parameters.Signal.Destination.WaveformFile=fullfile(WaveformSavepath, WaveformSavefile);
                app.SaveWavefomAsedt.Value=app.Parameters.Signal.Destination.WaveformFile;
            end
        end

        % Button pushed function: SaveWaveformDir
        function SaveWaveformDirButtonPushed(app, event)
            defaultDir=app.Parameters.Signal.Destination.Path;
            dialogText='Select folder to save waveforms';
            directoryName = uigetdir(defaultDir,dialogText);
            if directoryName~=0
                app.Parameters.Signal.Destination.Path=directoryName;
                app.SaveWavefomsDiredt.Value=directoryName;
            end
        end

        % Callback function
        function SegmentPreviewTimeValueChanged(app, event)
            app.Parameters.Preview.SegmentPreviewTime = app.SegmentPreviewTime.Value;           
        end

        % Button pushed function: SelectABI
        function SelectABIButtonPushed(app, event)
                       
            %numABISignals=1;
             valIndx = strcmp(app.RadarFiles.ItemsData, app.RadarFiles.Value);
            if ~ismember(app.RadarFiles.Items(valIndx),app.SelectedABIFiles.Items)
                %newSelectedRadarFilesItems=horzcat(app.SelectedRadarFiles.Items,app.RadarFiles.Items(valIndx));
                app.SelectedABIFiles.Items=horzcat(app.SelectedABIFiles.Items,app.RadarFiles.Items(valIndx));
                app.SelectedABIFiles.ItemsData=horzcat(app.SelectedABIFiles.ItemsData,app.RadarFiles.ItemsData(valIndx));
                if length(app.SelectedABIFiles.Items)> app.numABISignals
                    app.SelectedABIFiles.Items(1)=[];
                    app.SelectedABIFiles.ItemsData(1)=[];
                end
                %app.SelectedRadarFiles.Items=newSelectedRadarFilesItems;
                %ItemsDataIndx=strcmp(app.RadarFiles.Items, newSelectedRadarFilesItems);
                %app.SelectedRadarFiles.ItemsData=app.RadarFiles.ItemsData(ItemsDataIndx);
            end
            if length(app.SelectedABIFiles.Items)==app.numABISignals
                setFileSourcesFromSelectedFiles(app,'ABI')
            end
        end

        % Button pushed function: SelectDataFolderLTE
        function SelectDataFolderLTEButtonPushed(app, event)
            dialogText='Select LTE waveforms folder';
            defaultExt='dat';
            app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Signal.Source.Path.LTEDir);
            app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
            app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
            app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
            fileNames=getFileNames(app.folderBrowser,'withext');
            if ~isempty(fileNames)
                app.LTEFiles.Items=fileNames;
                %WaveformsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                
                %app.LTEFiles.ItemsData=WaveformsPaths;%1:length(fileNames);
                app.Parameters.Signal.Source.Path.LTEDir=getCurrentDir(app.folderBrowser);
                app.LTEDirLbl.Text=['LTE Data Dir: ',app.Parameters.Signal.Source.Path.LTEDir];
                app.LTEFiles.Items=fileNames;
                LTESignalsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                app.LTEFiles.ItemsData=LTESignalsPaths;%1:length(fileNames);
                
                app.SelectedLTEFiles.Items={};
                app.SelectedLTEFiles.ItemsData={};
            end 


        end

        % Button pushed function: SelectDataFolderRadar
        function SelectDataFolderRadarButtonPushed(app, event)
            dialogText='Select Radar waveforms folder';
            defaultExt='dat';
            app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Signal.Source.Path.RadarDir);
            app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
            app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
            app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
            fileNames=getFileNames(app.folderBrowser,'withext');
            assignin('base','fileNames',fileNames);
            if ~isempty(fileNames)
                app.Parameters.Signal.Source.Path.RadarDir=getCurrentDir(app.folderBrowser);
                
                app.RadarDirLbl.Text=['Radar Data Dir: ',app.Parameters.Signal.Source.Path.RadarDir];
                app.RadarFiles.Items=fileNames;
                radarSignalsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                %app.RadarFiles.ItemsData=1:length(fileNames);
                %app.RadarFiles.ItemsData=app.RadarFiles.Items;
                app.RadarFiles.ItemsData=radarSignalsPaths;
                %assignin('base','file_paths',radarSignalsPaths)
                %app.RadarFiles.Value=app.fileNo;
                app.SelectedRadarFiles.Items={};
                app.SelectedRadarFiles.ItemsData={};
                app.SelectedABIFiles.Items={};
                app.SelectedABIFiles.ItemsData={};
            end
            
            %clear fileNames;
        end

        % Button pushed function: SelectLTE
        function SelectLTEButtonPushed(app, event)
            %numLTESignals=2;
             valIndx = strcmp(app.LTEFiles.ItemsData, app.LTEFiles.Value);
            if ~ismember(app.LTEFiles.Items(valIndx),app.SelectedLTEFiles.Items)
                %newSelectedRadarFilesItems=horzcat(app.SelectedRadarFiles.Items,app.RadarFiles.Items(valIndx));
                app.SelectedLTEFiles.Items=horzcat(app.SelectedLTEFiles.Items,app.LTEFiles.Items(valIndx));
                app.SelectedLTEFiles.ItemsData=horzcat(app.SelectedLTEFiles.ItemsData,app.LTEFiles.ItemsData(valIndx));
                if length(app.SelectedLTEFiles.Items)> app.numLTESignals
                    app.SelectedLTEFiles.Items(1)=[];
                    app.SelectedLTEFiles.ItemsData(1)=[];
                end
                %app.SelectedRadarFiles.Items=newSelectedRadarFilesItems;
                %ItemsDataIndx=strcmp(app.RadarFiles.Items, newSelectedRadarFilesItems);
                %app.SelectedRadarFiles.ItemsData=app.RadarFiles.ItemsData(ItemsDataIndx);
            end
            if length(app.SelectedLTEFiles.Items)==app.numLTESignals
                setFileSourcesFromSelectedFiles(app,'LTE');
            end
        end

        % Button pushed function: SelectRadar
        function SelectRadarButtonPushed(app, event)
            %assignin('base','SelectedRadarFiles',app.SelectedRadarFiles.Items)
            %assignin('base','RadarFiles',app.RadarFiles.Value)
            valIndx = strcmp(app.RadarFiles.ItemsData, app.RadarFiles.Value);
            %numRadarSignals=2;
            if ~ismember(app.RadarFiles.Items(valIndx),app.SelectedRadarFiles.Items)
                %newSelectedRadarFilesItems=horzcat(app.SelectedRadarFiles.Items,app.RadarFiles.Items(valIndx));
                app.SelectedRadarFiles.Items=horzcat(app.SelectedRadarFiles.Items,app.RadarFiles.Items(valIndx));
                app.SelectedRadarFiles.ItemsData=horzcat(app.SelectedRadarFiles.ItemsData,app.RadarFiles.ItemsData(valIndx));
                if length(app.SelectedRadarFiles.Items)>app.numRadarSignals
                    app.SelectedRadarFiles.Items(1)=[];
                    app.SelectedRadarFiles.ItemsData(1)=[];
                end
                %app.SelectedRadarFiles.Items=newSelectedRadarFilesItems;
                %ItemsDataIndx=strcmp(app.RadarFiles.Items, newSelectedRadarFilesItems);
                %app.SelectedRadarFiles.ItemsData=app.RadarFiles.ItemsData(ItemsDataIndx);
            end
            %            assignin('base','SelectedRadarFiles',app.SelectedRadarFiles.Items)
            %            assignin('base','SelectedRadarFilesPaths',app.SelectedRadarFiles.ItemsData)
            if length(app.SelectedRadarFiles.Items)==app.numRadarSignals
                setFileSourcesFromSelectedFiles(app,'Radar')
            end
           
        end

        % Button pushed function: SelectRadarMetaDataFile
        function SelectRadarMetaDataFileButtonPushed(app, event)
            [RadarMeasInfoFile_name,...
                RadarMeasInfoFile_path] = ...
                uigetfile({'*.xlsx'},'Radar Meta Data File Selector');
            radarMetaFile=fullfile(RadarMeasInfoFile_path,...
                                            RadarMeasInfoFile_name);
            assignin('base','APPradarMetaFile',radarMetaFile);
            if (exist(radarMetaFile, 'file') == 2) %&& ismethod(app.waveformPervGen,'setapp.radarMetaFile')
                app.Parameters.Signal.Source.Path.radarMetaFile=radarMetaFile;
                app.RadarMetaFileLbl.Text=radarMetaFile;
                app.SelectRadarMetaDataFile.Enable= 'off';                
            else
                uialert(app.ESCWaveGen,'No Meta data file Selected and/ or select Radar measurement dir first','Invalid File');
            end           
        end

        % Value changed function: SelectWaveformFilePlayer
        function SelectWaveformFilePlayerValueChanged(app, event)
            app.DebugLbl.Text = app.SelectWaveformFilePlayer.Value;
            setUpWaveformPlayer(app);
            PlayPlayerButtonPushed(app);
        end

        % Button pushed function: SelectWaveformFolderPlayer
        function SelectWaveformFolderPlayerButtonPushed(app, event)
            dialogText='Select waveforms folder to play';
            defaultExt='dat';
            app.folderBrowser=setDefaultDir(app.folderBrowser,app.Parameters.Signal.Source.Path.RadarDir);
            app.folderBrowser=setDialogText(app.folderBrowser,dialogText);
            app.folderBrowser=setDefaultExt(app.folderBrowser,defaultExt);
            app.folderBrowser=SelectFolder(app.folderBrowser,app.ESCWaveGen);
            fileNames=getFileNames(app.folderBrowser,'withext');
            if ~isempty(fileNames)
                app.SelectWaveformFilePlayer.Items=fileNames;
                WaveformsPaths=getFullFileNamesWithPath(app.folderBrowser,'withext');
                app.SelectWaveformFilePlayer.ItemsData=WaveformsPaths;%1:length(fileNames);
                % set selected to the first file
                app.SelectWaveformFilePlayer.Value=char(app.SelectWaveformFilePlayer.ItemsData(1));
                           
                setUpWaveformPlayer(app);
            end
            
        end

        % Value changed function: SetNumberofWorkers
        function SetNumberofWorkersValueChanged(app, event)
         app.Parameters.SignalMulti.NumberOfWorkers= app.SetNumberofWorkers.Value;
        end

        % Value changed function: SetRadarReadScaleManually
        function SetRadarReadScaleManuallyValueChanged(app, event)
            app.Parameters.Signal.ManualRadarReadScale= app.SetRadarReadScaleManually.Value;
            if app.Parameters.Signal.ManualRadarReadScale
                app.RadarReadScaleEdt.Enable='on';
            else
                app.RadarReadScaleEdt.Enable='off';
            end
        end

        % Value changed function: SetdataScaleGainEdt
        function SetdataScaleGainEdtValueChanged(app, event)
            %value = app.SetdataScaleGainEdt.Value;
            app.waveformPlayer=setReadScale(app.waveformPlayer,app.SetdataScaleGainEdt.Value);
        end

        % Button pushed function: ShowPeaks
        function ShowPeaksPushed(app, event)
            try
                if isempty(app.peaksFigure) ||~ishandle(app.peaksFigure)
                    app.peaksFigure = uifigure;
                    app.peaksAxes = uiaxes(app.peaksFigure,'Position',[10 10 550 400]);
                end
                    hold(app.peaksAxes,'on');
                for I=app.waveformPervGen.numRadarSignals
                    stem(app.peaksAxes,app.waveformPervGen.SIRData(I).original.locs, app.waveformPervGen.SIRData(I).original.pks);
                end
                   hold(app.peaksAxes,'off');
                   box(app.peaksAxes,'on');
                   grid(app.peaksAxes,'on');
            catch ME
                msg=['You need to selcect two radar files and preview wave waveform at least one time: ',ME.message];
                uialert(app.peaksFigure,msg,'Error: Peaks Plot');
            end
        end

        % Value changed function: ShowSIR
        function ShowSIRValueChanged(app, event)
            %value = app.ShowSIR.Value;
            
        end

        % Cell edit callback: SingleFileFixedPar1
        function SingleFileFixedPar1CellEdit(app, event)
            %             tempS= table2struct(cell2table(app.SingleFileFixedPar1.Data,...
            %                 'VariableNames',app.SingleFileFixedPar1.ColumnName.'));
            tempS=cell2struct(app.SingleFileFixedPar1.Data,app.SingleFileFixedPar1.ColumnName.',2);
            fieldNames=strrep(app.SingleFileFixedPar1.RowName, ' ', '');
            
            for I=1:length(fieldNames)
                app.Parameters.Signal.(fieldNames{I})=tempS(I);
            end
            %             
            %             app.Parameters.Signal.Status=tempS(1);app.Parameters.Signal.FrequencyOffset=tempS(2);
            %             app.Parameters.Signal.Gain=tempS(3);app.Parameters.Signal.StartTime=tempS(4);
           %assignin('base','ParametersTbl1',app.Parameters);
        end

        % Cell edit callback: SingleFileFixedParLTECh
        function SingleFileFixedParLTEChCellEdit(app, event)
            tempS=cell2struct(app.SingleFileFixedParLTECh.Data.',app.SingleFileFixedParLTECh.ColumnName.');
            app.Parameters.Signal.LTEChannelType=tempS;
        end

        % Callback function
        function SingleFileGenerationTable2CellEdit(app, event)
            if event.Indices==[11 1]
                % check if LTE channel is on and enable channel type Table
                %{
                if ~ismember(app.SingleFileFixedPar2.Data(5),app.ThreeGPPChPrCp.Items)
                    app.SingleFileFixedPar2.Data(5)={event.PreviousData};
                    wrnMsg=sprintf('please select one of these channel types:\n %s', strjoin(app.ThreeGPPChPrCp.Items));
                    uialert(app.ESCWaveGen,wrnMsg,'Invalid Channel Type');
                end
                %}
                Tbl2Data11=app.SingleFileFixedPar2.Data(11);
                %assignin('base','data7',app.SingleFileFixedPar2.Data(7));
                app.SingleFileFixedParLTECh.Enable=utilFun.logic2Enable(Tbl2Data11{1});
            end
            
            fieldNames=strrep(app.SingleFileFixedPar2.RowName, ' ', '');
            tempS=cell2struct(app.SingleFileFixedPar2.Data,fieldNames);
            for I=1:length(fieldNames)
                app.Parameters.Signal.(fieldNames{I})=tempS.(fieldNames{I});
            end
            %assignin('base','ParametersTbl2',app.Parameters);
        end

        % Callback function
        function SingleFileGenerationTable2CellSelection(app, event)
            %{
            if event.Indices==[5 1]
                app.ThreeGPPChPrCp.Visible='on';
            else
                app.ThreeGPPChPrCp.Visible='off';
            end
            %}
        end

        % Value changing function: SliderFreqOffsetABI
        function SliderFreqOffsetABIValueChanging(app, event)
            app.Parameters.Signal.FrequencyOffset.ABI=event.Value*1e6;
            app.ABIFreqNum.Value= app.Parameters.Signal.FrequencyOffset.ABI/1e6;
        end

        % Value changing function: SliderFreqOffsetLTE1
        function SliderFreqOffsetLTE1ValueChanging(app, event)
            %changingValue = event.Value;
            app.Parameters.Signal.FrequencyOffset.LTE1=event.Value*1e6;
            app.LTE1FreqNum.Value=app.Parameters.Signal.FrequencyOffset.LTE1/1e6;
        end

        % Value changing function: SliderFreqOffsetLTE2
        function SliderFreqOffsetLTE2ValueChanging(app, event)
            app.Parameters.Signal.FrequencyOffset.LTE2=event.Value*1e6;
            app.LTE2FreqNum.Value=app.Parameters.Signal.FrequencyOffset.LTE2/1e6;
        end

        % Value changing function: SliderFreqOffsetRadar1
        function SliderFreqOffsetRadar1ValueChanging(app, event)
            %changingValue = event.Value;
            app.Parameters.Signal.FrequencyOffset.Radar1=event.Value*1e6;
            app.Radar1FreqNum.Value= app.Parameters.Signal.FrequencyOffset.Radar1/1e6;
            % app.DebugLbl.Text=num2str(changingValue);
        end

        % Value changing function: SliderFreqOffsetRadar2
        function SliderFreqOffsetRadar2ValueChanging(app, event)
            app.Parameters.Signal.FrequencyOffset.Radar2=event.Value*1e6;
            app.Radar2FreqNum.Value= app.Parameters.Signal.FrequencyOffset.Radar2/1e6;
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            app.StopSimState=1;
            app.ReadyStateLbl.Text='Ready...';
            app.ReadyStateLbl.FontColor=[0.07,0.53,0.07];
        end

        % Button pushed function: StopPlayer
        function StopPlayerButtonPushed(app, event)
            app.StopPlayState=1;
        end

        % Value changed function: SwitchABI
        function SwitchABIValueChanged(app, event)
            app.ABILED.Enable = app.SwitchABI.Value;
            app.Parameters.Signal.Status.ABI=utilFun.enable2Logic(app.SwitchABI.Value);
        end

        % Value changed function: SwitchLTE1
        function SwitchLTE1ValueChanged(app, event)
            app.LTE1LED.Enable = app.SwitchLTE1.Value;
            app.Parameters.Signal.Status.LTE1=utilFun.enable2Logic(app.SwitchLTE1.Value);
        end

        % Value changed function: SwitchLTE2
        function SwitchLTE2ValueChanged(app, event)
            app.LTE2LED.Enable = app.SwitchLTE2.Value;
            app.Parameters.Signal.Status.LTE2=utilFun.enable2Logic(app.SwitchLTE2.Value);
        end

        % Value changed function: SwitchRadar1
        function SwitchRadar1ValueChanged(app, event)
            app.Radar1LED.Enable = app.SwitchRadar1.Value;
            app.Parameters.Signal.Status.Radar1=utilFun.enable2Logic(app.SwitchRadar1.Value);
            %logicR=utilFun.enable2Logic(app.SwitchRadar1.Value);
        end

        % Value changed function: SwitchRadar2
        function SwitchRadar2ValueChanged(app, event)
            app.Radar2LED.Enable = app.SwitchRadar2.Value;
            app.Parameters.Signal.Status.Radar2=utilFun.enable2Logic(app.SwitchRadar2.Value);
        end

        % Value changed function: TargetSIRdBEdt
        function TargetSIRdBEdtValueChanged(app, event)
            app.Parameters.Signal.TargetSIR = app.TargetSIRdBEdt.Value;
        end

        % Callback function
        function TotalPreviewTimeValueChanged(app, event)
            app.Parameters.Preview.TotalPreviewTime = app.TotalPreviewTime.Value;          
        end

        % Value changed function: WaveformFilePrefixedt
        function WaveformFilePrefixedtValueChanged(app, event)
          app.Parameters.Signal.Destination.FileNamePrefix = app.WaveformFilePrefixedt.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ESCWaveGen = uifigure;
            app.ESCWaveGen.Name = 'ESC Waveform Generator V 1.00';
            app.ESCWaveGen.CloseRequestFcn = createCallbackFcn(app, @ESCWaveGenCloseRequest, true);
            app.TabGroup = uitabgroup(app.ESCWaveGen);
            app.SignalSources = uitab(app.TabGroup);
            app.SignalSources.Title = 'Signal Sources';
            app.SelectDataFolderRadar = uibutton(app.SignalSources, 'push');
            app.SelectDataFolderRadar.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderRadarButtonPushed, true);
            app.SelectDataFolderRadar.Text = 'Select Radar Data Folder';
            app.RadarFilesLabel = uilabel(app.SignalSources);
            app.RadarFilesLabel.Text = 'Radar Files';
            app.RadarFiles = uilistbox(app.SignalSources);
            app.RadarFiles.Items = {};
            app.RadarFiles.ValueChangedFcn = createCallbackFcn(app, @RadarFilesValueChanged, true);
            app.RadarFiles.Value = {};
            app.SelectDataFolderLTE = uibutton(app.SignalSources, 'push');
            app.SelectDataFolderLTE.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderLTEButtonPushed, true);
            app.SelectDataFolderLTE.Text = 'Select LTE Data Folder';
            app.LTEDirLbl = uilabel(app.SignalSources);
            app.LTEDirLbl.Text = 'LTE Signals Dir:';
            app.RadarDirLbl = uilabel(app.SignalSources);
            app.RadarDirLbl.Text = 'Radar Signals Dir:';
            app.RadarMetaFileLbl = uilabel(app.SignalSources);
            app.RadarMetaFileLbl.Text = 'Radar Meta Data File: ';
            app.SelectRadarMetaDataFile = uibutton(app.SignalSources, 'push');
            app.SelectRadarMetaDataFile.ButtonPushedFcn = createCallbackFcn(app, @SelectRadarMetaDataFileButtonPushed, true);
            app.SelectRadarMetaDataFile.Text = 'Select Radar Meta Data File:';
            app.SetRadarReadScaleManually = uicheckbox(app.SignalSources);
            app.SetRadarReadScaleManually.ValueChangedFcn = createCallbackFcn(app, @SetRadarReadScaleManuallyValueChanged, true);
            app.SetRadarReadScaleManually.Text = 'Set Radar Read Scale Manually';
            app.RadarReadScaleEdt = uieditfield(app.SignalSources, 'numeric');
            app.RadarReadScaleEdt.ValueChangedFcn = createCallbackFcn(app, @RadarReadScaleEdtValueChanged, true);
            app.LTEFilesLabel = uilabel(app.SignalSources);
            app.LTEFilesLabel.Text = 'LTE Files';
            app.LTEFiles = uilistbox(app.SignalSources);
            app.LTEFiles.Items = {};
            app.LTEFiles.Value = {};
            app.SelectedLTEFilesLabel = uilabel(app.SignalSources);
            app.SelectedLTEFilesLabel.Text = 'Selected LTE Files';
            app.SelectedLTEFiles = uilistbox(app.SignalSources);
            app.SelectedLTEFiles.Items = {};
            app.SelectedLTEFiles.Value = {};
            app.SelectLTE = uibutton(app.SignalSources, 'push');
            app.SelectLTE.ButtonPushedFcn = createCallbackFcn(app, @SelectLTEButtonPushed, true);
            app.SelectLTE.Text = '>>';
            app.SelectRadar = uibutton(app.SignalSources, 'push');
            app.SelectRadar.ButtonPushedFcn = createCallbackFcn(app, @SelectRadarButtonPushed, true);
            app.SelectRadar.Text = '>>';
            app.SelectedRadarFilesLabel = uilabel(app.SignalSources);
            app.SelectedRadarFilesLabel.Text = 'Selected Radar Files';
            app.SelectedRadarFiles = uilistbox(app.SignalSources);
            app.SelectedRadarFiles.Items = {};
            app.SelectedRadarFiles.Value = {};
            app.SelectABI = uibutton(app.SignalSources, 'push');
            app.SelectABI.ButtonPushedFcn = createCallbackFcn(app, @SelectABIButtonPushed, true);
            app.SelectABI.Text = '>>';
            app.SelectedABIFilesLabel = uilabel(app.SignalSources);
            app.SelectedABIFilesLabel.Text = 'Selected ABI Files';
            app.SelectedABIFiles = uilistbox(app.SignalSources);
            app.SelectedABIFiles.Items = {};
            app.SelectedABIFiles.Value = {};
            app.LTEReadScaleEditFieldLabel = uilabel(app.SignalSources);
            app.LTEReadScaleEditFieldLabel.Text = 'LTE Read Scale';
            app.LTEReadScaleEdt = uieditfield(app.SignalSources, 'numeric');
            app.LTEReadScaleEdt.ValueChangedFcn = createCallbackFcn(app, @LTEReadScaleEdtValueChanged, true);
            app.LTEReadScaleEdt.Value = 0.0001;
            app.WaveformPreview = uitab(app.TabGroup);
            app.WaveformPreview.Title = 'Waveform Preview';
            app.SignalTiming = uipanel(app.WaveformPreview);
            app.SignalTiming.Title = 'Signal Timing (sec)';
            app.TotalpreviewLabel = uilabel(app.SignalTiming);
            app.TotalpreviewLabel.Text = 'Total preview ';
            app.TotalPreviewTime = uieditfield(app.SignalTiming, 'numeric');
            app.TotalPreviewTime.ValueChangedFcn = createCallbackFcn(app, @TotalPreviewTimeValueChanged, true);
            app.TotalPreviewTime.Limits = [0.1 60];
            app.TotalPreviewTime.Value = 1;
            app.SegmentPreviewTimeLabel = uilabel(app.SignalTiming);
            app.SegmentPreviewTimeLabel.Text = 'Segment preview';
            app.SegmentPreviewTime = uieditfield(app.SignalTiming, 'numeric');
            app.SegmentPreviewTime.Limits = [0.001 1];
            app.SegmentPreviewTime.Value = 0.01;
            app.PanelLTE2 = uipanel(app.WaveformPreview);
            app.PanelLTE2.Title = 'LTE #2';
            app.LTE2LED = uilamp(app.PanelLTE2);
            app.LTE2LED.Enable = 'off';
            app.SliderFreqOffsetLTE2 = uislider(app.PanelLTE2);
            app.SliderFreqOffsetLTE2.Limits = [-12 12];
            app.SliderFreqOffsetLTE2.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetLTE2ValueChanging, true);
            app.SliderFreqOffsetLTE2.MinorTicks = [];
            app.SliderFreqOffsetLTE2.Value = 5;
            app.Label13 = uilabel(app.PanelLTE2);
            app.Label13.Text = 'Frequency Offset MHz';
            app.Label15 = uilabel(app.PanelLTE2);
            app.Label15.Text = '';
            app.SwitchLTE2 = uiswitch(app.PanelLTE2, 'toggle');
            app.SwitchLTE2.ValueChangedFcn = createCallbackFcn(app, @SwitchLTE2ValueChanged, true);
            app.LTE2FreqNum = uieditfield(app.PanelLTE2, 'numeric');
            app.LTE2FreqNum.ValueChangedFcn = createCallbackFcn(app, @LTE2FreqNumValueChanged, true);
            app.LTE2FreqNum.Limits = [-12 12];
            app.Label14 = uilabel(app.PanelLTE2);
            app.Label14.Text = 'Gain';
            app.KnobGainLTE2 = uiknob(app.PanelLTE2, 'continuous');
            app.KnobGainLTE2.Limits = [0 0.1];
            app.KnobGainLTE2.ValueChangingFcn = createCallbackFcn(app, @KnobGainLTE2ValueChanging, true);
            app.KnobGainLTE2.Value = 0.0005;
            app.GainLTE2Num = uieditfield(app.PanelLTE2, 'numeric');
            app.GainLTE2Num.ValueChangedFcn = createCallbackFcn(app, @GainLTE2NumValueChanged, true);
            app.GainLTE2Num.ValueDisplayFormat = '%e';
            app.ChannelTypeLTE1_2Label = uilabel(app.PanelLTE2);
            app.ChannelTypeLTE1_2Label.Text = 'Channel Type';
            app.ChannelTypeLTE2 = uidropdown(app.PanelLTE2);
            app.ChannelTypeLTE2.ValueChangedFcn = createCallbackFcn(app, @ChannelTypeLTE2ValueChanged, true);
            app.PanelLTE1 = uipanel(app.WaveformPreview);
            app.PanelLTE1.Title = 'LTE #1';
            app.LTE1LED = uilamp(app.PanelLTE1);
            app.LTE1LED.Enable = 'off';
            app.SliderFreqOffsetLTE1 = uislider(app.PanelLTE1);
            app.SliderFreqOffsetLTE1.Limits = [-12 12];
            app.SliderFreqOffsetLTE1.MajorTickLabels = {'-12', '', '', '', '', '', '', '', '', '', '', '', '12'};
            app.SliderFreqOffsetLTE1.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetLTE1ValueChanging, true);
            app.SliderFreqOffsetLTE1.MinorTicks = [];
            app.SliderFreqOffsetLTE1.Value = -5;
            app.Label17 = uilabel(app.PanelLTE1);
            app.Label17.Text = 'Frequency Offset MHz';
            app.Label18 = uilabel(app.PanelLTE1);
            app.Label18.Text = 'Gain';
            app.KnobGainLTE1 = uiknob(app.PanelLTE1, 'continuous');
            app.KnobGainLTE1.Limits = [0 0.1];
            app.KnobGainLTE1.ValueChangingFcn = createCallbackFcn(app, @KnobGainLTE1ValueChanging, true);
            app.KnobGainLTE1.Value = 0.0005;
            app.Label19 = uilabel(app.PanelLTE1);
            app.Label19.Text = '';
            app.SwitchLTE1 = uiswitch(app.PanelLTE1, 'toggle');
            app.SwitchLTE1.ValueChangedFcn = createCallbackFcn(app, @SwitchLTE1ValueChanged, true);
            app.LTE1FreqNum = uieditfield(app.PanelLTE1, 'numeric');
            app.LTE1FreqNum.ValueChangedFcn = createCallbackFcn(app, @LTE1FreqNumValueChanged, true);
            app.LTE1FreqNum.Limits = [-12 12];
            app.GainLTE1Num = uieditfield(app.PanelLTE1, 'numeric');
            app.GainLTE1Num.ValueChangedFcn = createCallbackFcn(app, @GainLTE1NumValueChanged, true);
            app.GainLTE1Num.ValueDisplayFormat = '%e';
            app.ChannelTypeDropDownLabel = uilabel(app.PanelLTE1);
            app.ChannelTypeDropDownLabel.Text = 'Channel Type';
            app.ChannelTypeLTE1 = uidropdown(app.PanelLTE1);
            app.ChannelTypeLTE1.ValueChangedFcn = createCallbackFcn(app, @ChannelTypeLTE1ValueChanged, true);
            app.PanelRadar1 = uipanel(app.WaveformPreview);
            app.PanelRadar1.Title = 'Radar #1';
            app.Radar1LED = uilamp(app.PanelRadar1);
            app.Radar1LED.Enable = 'off';
            app.SliderFreqOffsetRadar1 = uislider(app.PanelRadar1);
            app.SliderFreqOffsetRadar1.Limits = [-12 12];
            app.SliderFreqOffsetRadar1.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetRadar1ValueChanging, true);
            app.SliderFreqOffsetRadar1.MinorTicks = [];
            app.Label21 = uilabel(app.PanelRadar1);
            app.Label21.Text = 'Frequency Offset MHz';
            app.Label22 = uilabel(app.PanelRadar1);
            app.Label22.Text = 'Gain';
            app.KnobGainRadar1 = uiknob(app.PanelRadar1, 'continuous');
            app.KnobGainRadar1.Limits = [0 4];
            app.KnobGainRadar1.ValueChangingFcn = createCallbackFcn(app, @KnobGainRadar1ValueChanging, true);
            app.KnobGainRadar1.Value = 1;
            app.SwitchRadar1 = uiswitch(app.PanelRadar1, 'toggle');
            app.SwitchRadar1.ValueChangedFcn = createCallbackFcn(app, @SwitchRadar1ValueChanged, true);
            app.Radar1FreqNum = uieditfield(app.PanelRadar1, 'numeric');
            app.Radar1FreqNum.ValueChangedFcn = createCallbackFcn(app, @Radar1FreqNumValueChanged, true);
            app.Radar1FreqNum.Limits = [-12 12];
            app.GainRadar1Num = uieditfield(app.PanelRadar1, 'numeric');
            app.GainRadar1Num.ValueChangedFcn = createCallbackFcn(app, @GainRadar1NumValueChanged, true);
            app.GainRadar1Num.ValueDisplayFormat = '%2.5f';
            app.Label23 = uilabel(app.PanelRadar1);
            app.Label23.Text = '';
            app.Waveforms = uipanel(app.WaveformPreview);
            app.Waveforms.Title = 'Waveforms';
            app.Stop = uibutton(app.Waveforms, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = 'Stop';
            app.Preview = uibutton(app.Waveforms, 'push');
            app.Preview.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.Preview.Text = 'Preview';
            app.DisplayPreview = uibuttongroup(app.Waveforms);
            app.DisplayPreview.SelectionChangedFcn = createCallbackFcn(app, @DisplayPreviewSelectionChanged, true);
            app.DisplayPreview.Title = 'Signal Display';
            app.Frequency = uiradiobutton(app.DisplayPreview);
            app.Frequency.Text = 'Frequency';
            app.Frequency.Value = true;
            app.Time = uiradiobutton(app.DisplayPreview);
            app.Time.Text = 'Time';
            app.Spectrogram = uiradiobutton(app.DisplayPreview);
            app.Spectrogram.Text = 'Spectrogram';
            app.ReadyStateLbl = uilabel(app.Waveforms);
            app.ReadyStateLbl.Text = 'Ready...';
            app.SIRLbl = uilabel(app.Waveforms);
            app.SIRLbl.Text = 'SIR';
            app.ShowSIR = uicheckbox(app.Waveforms);
            app.ShowSIR.ValueChangedFcn = createCallbackFcn(app, @ShowSIRValueChanged, true);
            app.ShowSIR.Text = 'Show Instantaneous SIR';
            app.LTEChannelState = uicheckbox(app.WaveformPreview);
            app.LTEChannelState.ValueChangedFcn = createCallbackFcn(app, @LTEChannelStateValueChanged, true);
            app.LTEChannelState.Text = 'LTE Channel';
            app.LoadDefaults = uibutton(app.WaveformPreview, 'push');
            app.LoadDefaults.ButtonPushedFcn = createCallbackFcn(app, @LoadDefaultsButtonPushed, true);
            app.LoadDefaults.Text = 'Load Defaults';
            app.PanelRadar2 = uipanel(app.WaveformPreview);
            app.PanelRadar2.Title = 'Radar #2';
            app.Radar2LED = uilamp(app.PanelRadar2);
            app.Radar2LED.Enable = 'off';
            app.SliderFreqOffsetRadar2 = uislider(app.PanelRadar2);
            app.SliderFreqOffsetRadar2.Limits = [-12 12];
            app.SliderFreqOffsetRadar2.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetRadar2ValueChanging, true);
            app.SliderFreqOffsetRadar2.MinorTicks = [];
            app.Label21_2 = uilabel(app.PanelRadar2);
            app.Label21_2.Text = 'Frequency Offset MHz';
            app.Label22_2 = uilabel(app.PanelRadar2);
            app.Label22_2.Text = 'Gain';
            app.KnobGainRadar2 = uiknob(app.PanelRadar2, 'continuous');
            app.KnobGainRadar2.Limits = [0 4];
            app.KnobGainRadar2.ValueChangingFcn = createCallbackFcn(app, @KnobGainRadar2ValueChanging, true);
            app.KnobGainRadar2.Value = 1;
            app.SwitchRadar2 = uiswitch(app.PanelRadar2, 'toggle');
            app.SwitchRadar2.ValueChangedFcn = createCallbackFcn(app, @SwitchRadar2ValueChanged, true);
            app.Label23_2 = uilabel(app.PanelRadar2);
            app.Label23_2.Text = '';
            app.Radar2FreqNum = uieditfield(app.PanelRadar2, 'numeric');
            app.Radar2FreqNum.ValueChangedFcn = createCallbackFcn(app, @Radar2FreqNumValueChanged, true);
            app.Radar2FreqNum.Limits = [-12 12];
            app.GainRadar2Num = uieditfield(app.PanelRadar2, 'numeric');
            app.GainRadar2Num.ValueChangedFcn = createCallbackFcn(app, @GainRadar2NumValueChanged, true);
            app.GainRadar2Num.ValueDisplayFormat = '%2.5f';
            app.PanelABI = uipanel(app.WaveformPreview);
            app.PanelABI.Title = 'Adjacent Band Interference (ABI)';
            app.ABILED = uilamp(app.PanelABI);
            app.ABILED.Enable = 'off';
            app.SliderFreqOffsetABI = uislider(app.PanelABI);
            app.SliderFreqOffsetABI.Limits = [-12 12];
            app.SliderFreqOffsetABI.ValueChangingFcn = createCallbackFcn(app, @SliderFreqOffsetABIValueChanging, true);
            app.SliderFreqOffsetABI.MinorTicks = [];
            app.Label21_3 = uilabel(app.PanelABI);
            app.Label21_3.Text = 'Frequency Offset MHz';
            app.Label22_3 = uilabel(app.PanelABI);
            app.Label22_3.Text = 'Gain';
            app.KnobGainABI = uiknob(app.PanelABI, 'continuous');
            app.KnobGainABI.Limits = [0 4];
            app.KnobGainABI.ValueChangingFcn = createCallbackFcn(app, @KnobGainABIValueChanging, true);
            app.KnobGainABI.Value = 1;
            app.SwitchABI = uiswitch(app.PanelABI, 'toggle');
            app.SwitchABI.ValueChangedFcn = createCallbackFcn(app, @SwitchABIValueChanged, true);
            app.Label23_3 = uilabel(app.PanelABI);
            app.Label23_3.Text = '';
            app.ABIFreqNum = uieditfield(app.PanelABI, 'numeric');
            app.ABIFreqNum.ValueChangedFcn = createCallbackFcn(app, @ABIFreqNumValueChanged, true);
            app.ABIFreqNum.Limits = [-12 12];
            app.GainABINum = uieditfield(app.PanelABI, 'numeric');
            app.GainABINum.ValueChangedFcn = createCallbackFcn(app, @GainABINumValueChanged, true);
            app.GainABINum.ValueDisplayFormat = '%2.5f';
            app.AWGNPanel = uipanel(app.WaveformPreview);
            app.AWGN = uicheckbox(app.AWGNPanel);
            app.AWGN.ValueChangedFcn = createCallbackFcn(app, @AWGNValueChanged, true);
            app.AWGN.Text = 'AWGN';
            app.AWGNVar = uislider(app.AWGNPanel);
            app.AWGNVar.Limits = [0 1e-07];
            app.AWGNVar.ValueChangingFcn = createCallbackFcn(app, @AWGNVarValueChanging, true);
            app.AWGNVar.Value = 1e-10;
            app.AWGNVarNum = uieditfield(app.AWGNPanel, 'numeric');
            app.AWGNVarNum.ValueChangedFcn = createCallbackFcn(app, @AWGNVarNumValueChanged, true);
            app.AWGNVarNum.ValueDisplayFormat = '%e';
            app.TargetSIRPanel = uipanel(app.WaveformPreview);
            app.TargetSIRdBEditFieldLabel = uilabel(app.TargetSIRPanel);
            app.TargetSIRdBEditFieldLabel.Text = 'Target SIR dB';
            app.TargetSIRdBEdt = uieditfield(app.TargetSIRPanel, 'numeric');
            app.TargetSIRdBEdt.ValueChangedFcn = createCallbackFcn(app, @TargetSIRdBEdtValueChanged, true);
            app.ForwardPreviewSignalssecPanel = uipanel(app.WaveformPreview);
            app.ForwardPreviewSignalssecPanel.Title = 'Forward Preview Signals (sec)';
            app.Radar1Label = uilabel(app.ForwardPreviewSignalssecPanel);
            app.Radar1Label.Text = 'Radar #1';
            app.ForwardRadar1 = uieditfield(app.ForwardPreviewSignalssecPanel, 'numeric');
            app.ForwardRadar1.ValueChangedFcn = createCallbackFcn(app, @ForwardRadar1ValueChanged, true);
            app.ForwardRadar1.Limits = [0 60];
            app.ForwardRadar1.Value = 1.36;
            app.Radar2Label = uilabel(app.ForwardPreviewSignalssecPanel);
            app.Radar2Label.Text = 'Radar #2';
            app.ForwardRadar2 = uieditfield(app.ForwardPreviewSignalssecPanel, 'numeric');
            app.ForwardRadar2.ValueChangedFcn = createCallbackFcn(app, @ForwardRadar2ValueChanged, true);
            app.ForwardRadar2.Limits = [0 60];
            app.ForwardRadar2.Value = 1.36;
            app.ABILabel = uilabel(app.ForwardPreviewSignalssecPanel);
            app.ABILabel.Text = 'ABI';
            app.ForwardABI = uieditfield(app.ForwardPreviewSignalssecPanel, 'numeric');
            app.ForwardABI.ValueChangedFcn = createCallbackFcn(app, @ForwardABIValueChanged, true);
            app.ForwardABI.Limits = [0 60];
            app.ForwardABI.Value = 1.36;
            app.LTE1Label = uilabel(app.ForwardPreviewSignalssecPanel);
            app.LTE1Label.Text = 'LTE #1';
            app.ForwardLTE1 = uieditfield(app.ForwardPreviewSignalssecPanel, 'numeric');
            app.ForwardLTE1.ValueChangedFcn = createCallbackFcn(app, @ForwardLTE1ValueChanged, true);
            app.ForwardLTE1.Limits = [0 60];
            app.ForwardLTE1.Value = 1.36;
            app.LTE1Label_2 = uilabel(app.ForwardPreviewSignalssecPanel);
            app.LTE1Label_2.Text = 'LTE #1';
            app.ForwardLTE2 = uieditfield(app.ForwardPreviewSignalssecPanel, 'numeric');
            app.ForwardLTE2.ValueChangedFcn = createCallbackFcn(app, @ForwardLTE2ValueChanged, true);
            app.ForwardLTE2.Limits = [0 60];
            app.ForwardLTE2.Value = 1.36;
            app.ShowPeaks = uibutton(app.WaveformPreview, 'push');
            app.ShowPeaks.ButtonPushedFcn = createCallbackFcn(app, @ShowPeaksPushed, true);
            app.ShowPeaks.Text = 'Show Peaks';
            app.SignalPowerLevels = uipanel(app.WaveformPreview);
            app.SignalPowerLevels.Title = 'Signal power reference dBm';
            app.RadarPeakPowerEditFieldLabel = uilabel(app.SignalPowerLevels);
            app.RadarPeakPowerEditFieldLabel.Text = 'Radar Peak Power';
            app.RadarPeakPowerEdt = uieditfield(app.SignalPowerLevels, 'numeric');
            app.RadarPeakPowerEdt.ValueChangedFcn = createCallbackFcn(app, @RadarPeakPowerEdtValueChanged, true);
            app.LTEPowerEditFieldLabel = uilabel(app.SignalPowerLevels);
            app.LTEPowerEditFieldLabel.Text = 'LTE Power';
            app.LTEPowerEdt = uieditfield(app.SignalPowerLevels, 'numeric');
            app.LTEPowerEdt.ValueChangedFcn = createCallbackFcn(app, @LTEPowerEdtValueChanged, true);
            app.ABIPowerEditFieldLabel = uilabel(app.SignalPowerLevels);
            app.ABIPowerEditFieldLabel.Text = 'ABI Power';
            app.ABIPowerEdt = uieditfield(app.SignalPowerLevels, 'numeric');
            app.ABIPowerEdt.ValueChangedFcn = createCallbackFcn(app, @ABIPowerEdtValueChanged, true);
            app.AWGNPSDEditFieldLabel = uilabel(app.SignalPowerLevels);
            app.AWGNPSDEditFieldLabel.Text = 'AWGN PSD';
            app.AWGNPSDEdt = uieditfield(app.SignalPowerLevels, 'numeric');
            app.AWGNPSDEdt.ValueChangedFcn = createCallbackFcn(app, @AWGNPSDEdtValueChanged, true);
            app.GainMethodDropDownLabel = uilabel(app.WaveformPreview);
            app.GainMethodDropDownLabel.Text = 'Gain Method';
            app.GainMethod = uidropdown(app.WaveformPreview);
            app.GainMethod.Items = {};
            app.GainMethod.ValueChangedFcn = createCallbackFcn(app, @GainMethodValueChanged, true);
            app.GainMethod.Value = {};
            app.EstimateGainParametersButton = uibutton(app.WaveformPreview, 'push');
            app.EstimateGainParametersButton.ButtonPushedFcn = createCallbackFcn(app, @EstimateGainParametersButtonPushed, true);
            app.EstimateGainParametersButton.Text = 'Estimate Gain Parameters';
            app.WaveformGenerationTab = uitab(app.TabGroup);
            app.WaveformGenerationTab.Title = 'Waveform Generation';
            app.SingleFileGenerationPanel = uipanel(app.WaveformGenerationTab);
            app.SingleFileGenerationPanel.Title = 'Single File Generation';
            app.SaveWaveformAs = uibutton(app.SingleFileGenerationPanel, 'push');
            app.SaveWaveformAs.ButtonPushedFcn = createCallbackFcn(app, @SaveWaveformAsButtonPushed, true);
            app.SaveWaveformAs.Text = 'Save Waveform As';
            app.SingleFileGenIndlbl = uilabel(app.SingleFileGenerationPanel);
            app.SingleFileGenIndlbl.Text = 'Ready';
            app.SaveWavefomAsedt = uieditfield(app.SingleFileGenerationPanel, 'text');
            app.SaveWavefomAsedt.ValueChangedFcn = createCallbackFcn(app, @SaveWavefomAsedtValueChanged, true);
            app.GenerateThisWaveform = uibutton(app.SingleFileGenerationPanel, 'push');
            app.GenerateThisWaveform.ButtonPushedFcn = createCallbackFcn(app, @GenerateThisWaveformButtonPushed, true);
            app.GenerateThisWaveform.Text = 'Generate This Waveform';
            app.MultipleFileGenerationPanel = uipanel(app.WaveformGenerationTab);
            app.MultipleFileGenerationPanel.Title = 'Multiple File Generation';
            app.SaveWaveformDir = uibutton(app.MultipleFileGenerationPanel, 'push');
            app.SaveWaveformDir.ButtonPushedFcn = createCallbackFcn(app, @SaveWaveformDirButtonPushed, true);
            app.SaveWaveformDir.Text = 'Save Waveform Dir';
            app.GenerateWaveforms = uibutton(app.MultipleFileGenerationPanel, 'push');
            app.GenerateWaveforms.ButtonPushedFcn = createCallbackFcn(app, @GenerateWaveformsButtonPushed, true);
            app.GenerateWaveforms.Text = 'Generate Waveforms';
            app.SaveWavefomsDiredt = uieditfield(app.MultipleFileGenerationPanel, 'text');
            app.SaveWavefomsDiredt.ValueChangedFcn = createCallbackFcn(app, @SaveWavefomsDiredtValueChanged, true);
            app.ParallelProcess = uicheckbox(app.MultipleFileGenerationPanel);
            app.ParallelProcess.ValueChangedFcn = createCallbackFcn(app, @ParallelProcessValueChanged, true);
            app.ParallelProcess.Text = 'Parallel Process';
            app.SetNumberofWorkersLabel = uilabel(app.MultipleFileGenerationPanel);
            app.SetNumberofWorkersLabel.Text = 'Set Number of Workers';
            app.SetNumberofWorkers = uispinner(app.MultipleFileGenerationPanel);
            app.SetNumberofWorkers.ValueChangedFcn = createCallbackFcn(app, @SetNumberofWorkersValueChanged, true);
            app.SetNumberofWorkers.Limits = [1 100];
            app.SetNumberofWorkers.ValueDisplayFormat = '%.0f';
            app.SetNumberofWorkers.Value = 20;
            app.AvailableNumberofWorkers = uilabel(app.MultipleFileGenerationPanel);
            app.AvailableNumberofWorkers.Text = 'Available Number of Workers=';
            app.MultiFileGenIndlbl = uilabel(app.MultipleFileGenerationPanel);
            app.MultiFileGenIndlbl.Text = 'Ready';
            app.WaveformFilePrefixLabel = uilabel(app.MultipleFileGenerationPanel);
            app.WaveformFilePrefixLabel.Text = 'Waveform File Prefix';
            app.WaveformFilePrefixedt = uieditfield(app.MultipleFileGenerationPanel, 'text');
            app.WaveformFilePrefixedt.ValueChangedFcn = createCallbackFcn(app, @WaveformFilePrefixedtValueChanged, true);
            app.NumberofFilesSpinnerLabel = uilabel(app.MultipleFileGenerationPanel);
            app.NumberofFilesSpinnerLabel.Text = 'Number of Files';
            app.NumberOfFiles = uispinner(app.MultipleFileGenerationPanel);
            app.NumberOfFiles.ValueChangedFcn = createCallbackFcn(app, @NumberOfFilesValueChanged, true);
            app.MultiFileMethod = uitable(app.MultipleFileGenerationPanel);
            app.MultiFileMethod.ColumnName = {'Column 1'};
            app.MultiFileMethod.RowName = {};
            app.MultiFileMethod.CellEditCallback = createCallbackFcn(app, @MultiFileMethodCellEdit, true);
            app.MultiFileStep = uitable(app.MultipleFileGenerationPanel);
            app.MultiFileStep.ColumnName = {'Column 1'};
            app.MultiFileStep.RowName = {};
            app.MultiFileStep.ColumnEditable = true;
            app.MultiFileStep.CellEditCallback = createCallbackFcn(app, @MultiFileStepCellEdit, true);
            app.MultiFileBounds = uitable(app.MultipleFileGenerationPanel);
            app.MultiFileBounds.ColumnName = {'Column 1'};
            app.MultiFileBounds.RowName = {};
            app.MultiFileBounds.CellEditCallback = createCallbackFcn(app, @MultiFileBoundsCellEdit, true);
            app.FileSources = uipanel(app.MultipleFileGenerationPanel);
            app.FileSources.Title = 'File Sources: (Change in signal sources tab)';
            app.RadarSignalsDirGen = uilabel(app.FileSources);
            app.RadarSignalsDirGen.Text = 'Radar Signals Dir:';
            app.RadarMetaFileGen = uilabel(app.FileSources);
            app.RadarMetaFileGen.Text = 'Radar Meta File: ';
            app.LTESignalsDirGen = uilabel(app.FileSources);
            app.LTESignalsDirGen.Text = 'LTE Signals Dir:';
            app.FixedParametersTimesecFrequencyPanel = uipanel(app.WaveformGenerationTab);
            app.FixedParametersTimesecFrequencyPanel.Title = 'Fixed Parameters (Time:sec, Frequency:Hz, SIR:dB)';
            app.SingleFileFixedPar1 = uitable(app.FixedParametersTimesecFrequencyPanel);
            app.SingleFileFixedPar1.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'};
            app.SingleFileFixedPar1.RowName = {};
            app.SingleFileFixedPar1.ColumnEditable = true;
            app.SingleFileFixedPar1.CellEditCallback = createCallbackFcn(app, @SingleFileFixedPar1CellEdit, true);
            app.SingleFileFixedPar2 = uitable(app.FixedParametersTimesecFrequencyPanel);
            app.SingleFileFixedPar2.ColumnName = '';
            app.SingleFileFixedPar2.RowName = {};
            app.SingleFileFixedPar2.CellEditCallback = createCallbackFcn(app, @SingleFileGenerationTable2CellEdit, true);
            app.SingleFileFixedPar2.CellSelectionCallback = createCallbackFcn(app, @SingleFileGenerationTable2CellSelection, true);
            app.SingleFileFixedParLTECh = uitable(app.FixedParametersTimesecFrequencyPanel);
            app.SingleFileFixedParLTECh.ColumnName = {'Column 1'; 'Column 2'};
            app.SingleFileFixedParLTECh.RowName = {};
            app.SingleFileFixedParLTECh.CellEditCallback = createCallbackFcn(app, @SingleFileFixedParLTEChCellEdit, true);
            app.PowerLevels = uitable(app.FixedParametersTimesecFrequencyPanel);
            app.PowerLevels.ColumnName = {'Column 1'};
            app.PowerLevels.RowName = {};
            app.PowerLevels.CellEditCallback = createCallbackFcn(app, @PowerLevelsCellEdit, true);
            app.GainMethodGen = uibuttongroup(app.FixedParametersTimesecFrequencyPanel);
            app.GainMethodGen.SelectionChangedFcn = createCallbackFcn(app, @GainMethodGenSelectionChanged, true);
            app.GainMethodGen.Title = 'Gain Method';
            app.TargetSIRMethod = uiradiobutton(app.GainMethodGen);
            app.TargetSIRMethod.Text = 'Target SIR';
            app.TargetSIRMethod.Value = true;
            app.PowerLevelsMethod = uiradiobutton(app.GainMethodGen);
            app.PowerLevelsMethod.Text = 'Power Levels';
            app.WaveformPlayerTab = uitab(app.TabGroup);
            app.WaveformPlayerTab.Title = 'Waveform Player';
            app.DisplayPlayer = uibuttongroup(app.WaveformPlayerTab);
            app.DisplayPlayer.SelectionChangedFcn = createCallbackFcn(app, @DisplayPlayerSelectionChanged, true);
            app.DisplayPlayer.Title = 'Signal Display';
            app.FrequencyPlayer = uiradiobutton(app.DisplayPlayer);
            app.FrequencyPlayer.Text = 'Frequency';
            app.FrequencyPlayer.Value = true;
            app.TimePlayer = uiradiobutton(app.DisplayPlayer);
            app.TimePlayer.Text = 'Time';
            app.SpectrogramButton = uiradiobutton(app.DisplayPlayer);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.StopPlayer = uibutton(app.WaveformPlayerTab, 'push');
            app.StopPlayer.ButtonPushedFcn = createCallbackFcn(app, @StopPlayerButtonPushed, true);
            app.StopPlayer.Text = 'Stop';
            app.PlayPlayer = uibutton(app.WaveformPlayerTab, 'push');
            app.PlayPlayer.ButtonPushedFcn = createCallbackFcn(app, @PlayPlayerButtonPushed, true);
            app.PlayPlayer.Text = 'Play';
            app.SelectWaveformFolderPlayer = uibutton(app.WaveformPlayerTab, 'push');
            app.SelectWaveformFolderPlayer.ButtonPushedFcn = createCallbackFcn(app, @SelectWaveformFolderPlayerButtonPushed, true);
            app.SelectWaveformFolderPlayer.Text = 'Select Waveforms Data Folder';
            app.SelectRadarFile_2Label = uilabel(app.WaveformPlayerTab);
            app.SelectRadarFile_2Label.Text = 'Select File';
            app.SelectWaveformFilePlayer = uilistbox(app.WaveformPlayerTab);
            app.SelectWaveformFilePlayer.Items = {};
            app.SelectWaveformFilePlayer.ValueChangedFcn = createCallbackFcn(app, @SelectWaveformFilePlayerValueChanged, true);
            app.SelectWaveformFilePlayer.Value = {};
            app.WaveformInfoLabel = uilabel(app.WaveformPlayerTab);
            app.WaveformInfoLabel.Text = 'Waveform Info';
            app.WaveformInfo = uitextarea(app.WaveformPlayerTab);
            app.WaveformInfo.Editable = 'off';
            app.SetdataScaleGainEditFieldLabel = uilabel(app.WaveformPlayerTab);
            app.SetdataScaleGainEditFieldLabel.Text = 'Set data Scale/Gain';
            app.SetdataScaleGainEdt = uieditfield(app.WaveformPlayerTab, 'numeric');
            app.SetdataScaleGainEdt.ValueChangedFcn = createCallbackFcn(app, @SetdataScaleGainEdtValueChanged, true);
            app.SetdataScaleGainEdt.Limits = [0 10000000];
            app.SetdataScaleGainEdt.ValueDisplayFormat = '%e';
            app.SetdataScaleGainEdt.Value = 1;
            app.LoadPreviewParameters = uibutton(app.ESCWaveGen, 'push');
            app.LoadPreviewParameters.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviewParametersButtonPushed, true);
            app.LoadPreviewParameters.Text = 'Load Preview>>Generation Parameters';
            app.ResetSpectrumAnalyzerandTimeSope = uibutton(app.ESCWaveGen, 'push');
            app.ResetSpectrumAnalyzerandTimeSope.ButtonPushedFcn = createCallbackFcn(app, @ResetSpectrumAnalyzerandTimeSopeButtonPushed, true);
            app.ResetSpectrumAnalyzerandTimeSope.Text = 'Reset Spectrum Analyzer and Time Sope';
            app.DebugLbl = uilabel(app.ESCWaveGen);
            app.DebugLbl.Text = '';
            app.LogoImage = uiaxes(app.ESCWaveGen);
        end
    end

    methods (Access = public)

        % Construct app
        function app = ESCWaveformGenerator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ESCWaveGen)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ESCWaveGen)
        end
    end
end